classdef important_6phases_update18 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FrictionPanel_2                 matlab.ui.container.Panel
        CfkineticEditField_2            matlab.ui.control.NumericEditField
        CfkineticEditField_2Label       matlab.ui.control.Label
        CfstaticEditField_2             matlab.ui.control.NumericEditField
        CfstaticEditField_2Label        matlab.ui.control.Label
        KineticLabel                    matlab.ui.control.Label
        StaticLabel                     matlab.ui.control.Label
        Coefficient_of_frictionKnob_2   matlab.ui.control.Knob
        Coefficient_of_frictionKnob     matlab.ui.control.Knob
        LimitingVelocitymsecEditField_2  matlab.ui.control.NumericEditField
        LimitingVelocitymsecEditField_2Label  matlab.ui.control.Label
        RopeSpecificationsPanel         matlab.ui.container.Panel
        VerticalGapbetweenSupportsmEditField  matlab.ui.control.NumericEditField
        VerticalGapbetweenSupportsmEditFieldLabel  matlab.ui.control.Label
        TypeofRopeDropDown              matlab.ui.control.DropDown
        TypeofRopeDropDownLabel         matlab.ui.control.Label
        InitialParametersPanel          matlab.ui.container.Panel
        TemperatureConditionsButtonGroup  matlab.ui.container.ButtonGroup
        HotButton                       matlab.ui.control.RadioButton
        AmbientButton                   matlab.ui.control.RadioButton
        ColdButton                      matlab.ui.control.RadioButton
        TimeincrementEditField          matlab.ui.control.NumericEditField
        TimeincrementEditFieldLabel     matlab.ui.control.Label
        InitialVelocityEditField        matlab.ui.control.NumericEditField
        InitialVelocityEditFieldLabel   matlab.ui.control.Label
        ComputeResultButton             matlab.ui.control.Button
        RocketPropertiesPanel           matlab.ui.container.Panel
        UITable_2                       matlab.ui.control.Table
        PhaseFiringPlanDataPanel        matlab.ui.container.Panel
        UpdateDataButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        RopewayEstimationLabel          matlab.ui.control.Label
        GenerateGraphButton             matlab.ui.control.Button
        AirDragPanel                    matlab.ui.container.Panel
        TotalDragAreasqmEditField       matlab.ui.control.NumericEditField
        TotalDragAreasqmEditFieldLabel  matlab.ui.control.Label
        ShapeProfileButtonGroup         matlab.ui.container.ButtonGroup
        UnitButton                      matlab.ui.control.RadioButton
        OgiveButton                     matlab.ui.control.RadioButton
        ConicalButton                   matlab.ui.control.RadioButton
        DensityofairkgcubicmEditFieldLabel  matlab.ui.control.Label
        DensityofairkgcubicmEditField   matlab.ui.control.NumericEditField
        PayloadandClampSpecificationsPanel  matlab.ui.container.Panel
        DistancebetweenclampsmEditField  matlab.ui.control.NumericEditField
        DistancebetweenclampsmEditFieldLabel  matlab.ui.control.Label
        DistancebetweenlineofcenterofgravityandthrustlinemEditField  matlab.ui.control.NumericEditField
        GapLabel                        matlab.ui.control.Label
        CentreofgravityymEditField      matlab.ui.control.NumericEditField
        CentreofgravityymLabel          matlab.ui.control.Label
        CentreofgravityxmEditField      matlab.ui.control.NumericEditField
        CentreofgravityxmLabel          matlab.ui.control.Label
        MassofclampskgEditField         matlab.ui.control.NumericEditField
        MassofclampskgEditFieldLabel    matlab.ui.control.Label
        MassofPayloadkgEditField        matlab.ui.control.NumericEditField
        MassofPayloadkgEditFieldLabel   matlab.ui.control.Label
    end

    properties (Access = public)
        DialogApp
        
        temp='Cold';
        type=0;          
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global phaseplandata
            global RocketProp

            RocketProp=[50,20.5,29.5,1.85,22000,11.081;
                3.5,1.5,2,0.7,4230,2.1428;
                3,1	,2,0.3,4230,3.3333;
                55,35,20,5,15000,7;
                95,67,28,2.73,67000,24.5421];
            
            app.UITable_2.Data=RocketProp;

            phaseplandata=[0,100,200,300,400,500;
                0,0,0,0,0,0;
                0,0,0,0,0,0;
                0,0,0,0,0,0;
                0,0,0,0,0,0;
                0,0,0,0,0,0];
            app.UITable.Data=phaseplandata;

        end

        % Selection changed function: ShapeProfileButtonGroup
        function ShapeProfileButtonGroupSelectionChanged(app, event)
            selectedButton = app.ShapeProfileButtonGroup.SelectedObject;
            global velocity
            global vel_mach
            global cd
            if selectedButton==1
                for i=1:length(velocity)
                    if (vel_mach(i)>=0 && vel_mach(i)<0.4)
                        cd(i)=(((0.267021-0)/(0.4-0))*(vel_mach(i)-0))+0;
                    end
                    if (vel_mach(i)>=0.4 && vel_mach(i)<0.8)
                        cd(i)=(((0.302273-0.267021)/(0.8-0.4))*(vel_mach(i)-0.4))+0.267021;
                    end
                    if (vel_mach(i)>=0.8 && vel_mach(i)<0.9)
                        cd(i)=(((0.336733-0.302273)/(0.9-0.8))*(vel_mach(i)-0.8))+0.302273;
                    end
                    if (vel_mach(i)>=0.9 && vel_mach(i)<1)
                        cd(i)=(((0.508609-0.336733)/(1-0.9))*(vel_mach(i)-0.9))+0.336733;
                    end
                    if (vel_mach(i)>=1 && vel_mach(i)<1.1)
                        cd(i)=(((0.4584583-0.508609)/(1.1-1))*(vel_mach(i)-1))+0.508609;
                    end
                    if (vel_mach(i)>=1.1 && vel_mach(i)<1.2)
                        cd(i)=(((0.42766-0.4584583)/(1.2-1.1))*(vel_mach(i)-1.1))+0.4584583;
                    end
                    if (vel_mach(i)>=1.2 && vel_mach(i)<1.5)
                        cd(i)=(((0.373774-0.42766)/(1.5-1.2))*(vel_mach(i)-1.2))+0.42766;
                    end
                    if (vel_mach(i)>=1.5 && vel_mach(i)<2)
                        cd(i)=(((0.303144-0.373774)/(2-1.5))*(vel_mach(i)-1.5))+0.373774;
                    end
                    if (vel_mach(i)>=2 && vel_mach(i)<=3)
                        cd(i)=(((0.208512-0.303144)/(3-2))*(vel_mach(i)-2))+0.303144;
                    end
                end
            end
            if selectedButton==2
                for i=1:length(velocity)
                    if (vel_mach(i)>=0 && vel_mach(i)<0.4)
                        cd(i)=(((0.207789-0)/(0.4-0))*(vel_mach(i)-0))+0;
                    end
                    if (vel_mach(i)>=0.4 && vel_mach(i)<0.8)
                        cd(i)=(((0.223567-0.207789)/(0.8-0.4))*(vel_mach(i)-0.4))+0.207789;
                    end
                    if (vel_mach(i)>=0.8 && vel_mach(i)<0.9)
                        cd(i)=(((0.237768-0.223567)/(0.9-0.8))*(vel_mach(i)-0.8))+0.223567;
                    end
                    if (vel_mach(i)>=0.9 && vel_mach(i)<1)
                        cd(i)=(((0.39384-0.237768)/(1-0.9))*(vel_mach(i)-0.9))+0.237768;
                    end
                    if (vel_mach(i)>=1 && vel_mach(i)<1.1)
                        cd(i)=(((0.416134-0.39384)/(1.1-1))*(vel_mach(i)-1))+0.39384;
                    end
                    if (vel_mach(i)>=1.1 && vel_mach(i)<1.2)
                        cd(i)=(((0.406104-0.416134)/(1.2-1.1))*(vel_mach(i)-1.1))+0.416134;
                    end
                    if (vel_mach(i)>=1.2 && vel_mach(i)<1.5)
                        cd(i)=(((0.371936-0.406104)/(1.5-1.2))*(vel_mach(i)-1.2))+0.406104;
                    end
                    if (vel_mach(i)>=1.5 && vel_mach(i)<2)
                        cd(i)=(((0.31167-0.371936)/(2-1.5))*(vel_mach(i)-1.5))+0.371936;
                    end
                    if (vel_mach(i)>=2 && vel_mach(i)<=3)
                        cd(i)=(((0.225458-0.31167)/(3-2))*(vel_mach(i)-2))+0.31167;
                    end
                end
                if selectedButton==3
                    for i=1:length(velocity)
                        cd(i)=1;
                    end
                end
            end                
        end

        % Selection changed function: TemperatureConditionsButtonGroup
        function TemperatureConditionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.TemperatureConditionsButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Cold'
                    app.temp= 'Cold'; 
                    app.type=0; %cold
                case 'Ambient'
                    app.temp = 'Ambient';
                    app.type=1; %ambient
                case 'Hot'
                    app.temp= 'Hot';
                    app.type=2; %hot
            end
        end

        % Button pushed function: UpdateDataButton
        function UpdateDataButtonPushed(app, event)
            global phase_data
            global rocket_data
            
            phase_data=app.UITable.Data;
            rocket_data=app.UITable_2.Data;
        end

        % Clicked callback: TypeofRopeDropDown
        function TypeofRopeDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            global dia_arr
            global massperunitlen_arr
            global strength_arr
            if (item==1)
                dia_arr=[25,26,28,29,30,32,34,35,36,37,38,39,40];
                massperunitlen_arr=[3.50,3.79,4.40,4.72,5.05,5.74,6.48,6.87,7.27,7.68,8.10,8.53,8.97];
                strength_arr=[520,562,652,699,748,851,961,1020,1080,1140,1200,1260,1330];
            elseif (item==2)
                dia_arr=[12,13,14,16,18,19,20,22,24,25,26,28,30,32,34,36,38,40];
                massperunitlen_arr=[0.576,0.680,0.790,1.03,1.30,1.45,1.60,1.94,2.31,2.50,2.71,3.14,3.60,4.10,4.63,5.19,5.78,6.40];
                strength_arr=[101,118,137,179,227,252,280,338,402,437,472,548,628,715,807,905,1010,1120];
            else
                dia_arr=[10,12,14,16,18,20,22,24,25,26,28,29,30,31,32,34,35,36,38,40];
                massperunitlen_arr=[0.380,0.540,0.740,0.960,1.22,1.50,1.82,2.16,2.34,2.54,2.94,3.15,3.38,4.34,4.59,4.86,5.42,6.00];
                strength_arr=[59,85,116,151,191,236,285,340.369,399,462,496,531,567,604,682,723,764,852,944];
            end
        end

        % Value changed function: Coefficient_of_frictionKnob
        function Coefficient_of_frictionKnobValueChanged(app, event)
            value = app.Coefficient_of_frictionKnob.Value;
            app.CfstaticEditField_2.Value=value;
        end

        % Value changing function: Coefficient_of_frictionKnob
        function Coefficient_of_frictionKnobValueChanging2(app, event)
            changingValue = event.Value;
            app.CfstaticEditField_2.Value=changingValue;
        end

        % Value changed function: Coefficient_of_frictionKnob_2
        function Coefficient_of_frictionKnob_2ValueChanged2(app, event)
            value = app.Coefficient_of_frictionKnob_2.Value;
            app.CfkineticEditField_2.Value=value;
        end

        % Value changing function: Coefficient_of_frictionKnob_2
        function Coefficient_of_frictionKnob_2ValueChanging2(app, event)
            changingValue = event.Value;
            app.CfkineticEditField_2.Value=changingValue;
        end

        % Button pushed function: ComputeResultButton
        function ComputeResultButtonPushed(app, event)
            global ProfileRocketTime
            global ProfileRocketThrust
            global phase_data
            global rocket_data
            global current_pos
            global current_time
            global velocity
            global acc
            global current_mass
            global thrust_current
            global net_force
            global vel_mach
            global cd

            ProfileRocketTime.grad=[0,0.0600,0.4200,0.4200,0.4200,2.1900,2.3400,0,0.0800,0.3500,0.3500,0.3500,1.9000,1.9500,0,0.0800,0.4000,0.4000,0.4000,1.7500,2.0000];
            ProfileRocketThrust.grad=[0,28500,17000,17000,17000,18200,0,0,42050,22100,22100,22100,21500,0,0,47820,22000,22000,22000,19500,0];
            ProfileRocketTime.rrm1=[0,0.1000,0.1700,0.1700,0.1700,2.8000,3.7800,0,0.1000,0.1700,0.1700,0.1700,2.6760,3.5000,0,0.1000,0.1700,0.1700,0.1700,2.5700,3.4700];
            ProfileRocketThrust.rrm1=[0,63900,53750,53750,53750,55900,0,0,57000,55500,55500,55500,54510,0,0,67330,59100,59100,59100,59900,0];

            mass_of_payload=app.MassofPayloadkgEditField.Value;
            if (isempty(mass_of_payload))
                warndlg("Please enter payload mass.");
            end

            mass_of_clamp=app.MassofclampskgEditField.Value;
            if (isempty(mass_of_clamp))
                warndlg("Please enter clamp mass.");
            end

            frontal_area=app.TotalDragAreasqmEditField.Value;
            if (isempty(frontal_area))
                warndlg("Please enter frontal area.");
            end

            density=app.DensityofairkgcubicmEditField.Value;

            coefficient_of_friction_s=app.Coefficient_of_frictionKnob.Value;

            coefficient_of_friction_k=app.Coefficient_of_frictionKnob_2.Value;

            limiting_velocity=app.LimitingVelocitymsecEditField_2.Value;
            if (isempty(limiting_velocity))
                warndlg("Please enter limiting velocity.");
            end 

            initial_distance=phase_data(1,1);
            initial_velocity=app.InitialVelocityEditField.Value;
            delta_t=app.TimeincrementEditField.Value;

            velocity=zeros(1,10000);
            current_time=zeros(1,10000);
            current_pos=zeros(1,1000);
            acc=zeros(1,10000);
            net_force=zeros(1,10000);
            thrust_current=zeros(1,10000);
            drag=zeros(1,10000);
            air_drag=zeros(1,10000);
            friction_drag=zeros(1,10000);
            current_mass=zeros(1,10000);
            coefficient_of_friction=zeros(1,10000);
            vel_mach=zeros(1,10000);
            cd=zeros(1,10000);

            time_p1=zeros(1,10000);
            time_p2=zeros(1,10000);
            time_p3=zeros(1,10000);
            time_p4=zeros(1,10000);
            time_p5=zeros(1,10000);
            time_p6=zeros(1,10000);

            mp_type1_p1=zeros(1,10000);
            mp_type1_p2=zeros(1,10000);
            mp_type1_p3=zeros(1,10000);
            mp_type1_p4=zeros(1,10000);
            mp_type1_p5=zeros(1,10000);
            mp_type1_p6=zeros(1,10000);

            mp_type2_p1=zeros(1,10000);
            mp_type2_p2=zeros(1,10000);
            mp_type2_p3=zeros(1,10000);
            mp_type2_p4=zeros(1,10000);
            mp_type2_p5=zeros(1,10000);
            mp_type2_p6=zeros(1,10000);

            mp_type3_p1=zeros(1,10000);
            mp_type3_p2=zeros(1,10000);
            mp_type3_p3=zeros(1,10000);
            mp_type3_p4=zeros(1,10000);
            mp_type3_p5=zeros(1,10000);
            mp_type3_p6=zeros(1,10000);

            mp_type4_p1=zeros(1,10000);
            mp_type4_p2=zeros(1,10000);
            mp_type4_p3=zeros(1,10000);
            mp_type4_p4=zeros(1,10000);
            mp_type4_p5=zeros(1,10000);
            mp_type4_p6=zeros(1,10000);

            mp_type5_p1=zeros(1,10000);
            mp_type5_p2=zeros(1,10000);
            mp_type5_p3=zeros(1,10000);
            mp_type5_p4=zeros(1,10000);
            mp_type5_p5=zeros(1,10000);
            mp_type5_p6=zeros(1,10000);

            thrust_type1_p1=zeros(1,10000);
            thrust_type1_p2=zeros(1,10000);
            thrust_type1_p3=zeros(1,10000);
            thrust_type1_p4=zeros(1,10000);
            thrust_type1_p5=zeros(1,10000);
            thrust_type1_p6=zeros(1,10000);

            thrust_type2_p1=zeros(1,10000);
            thrust_type2_p2=zeros(1,10000);
            thrust_type2_p3=zeros(1,10000);
            thrust_type2_p4=zeros(1,10000);
            thrust_type2_p5=zeros(1,10000);
            thrust_type2_p6=zeros(1,10000);

            thrust_type3_p1=zeros(1,10000);
            thrust_type3_p2=zeros(1,10000);
            thrust_type3_p3=zeros(1,10000);
            thrust_type3_p4=zeros(1,10000);
            thrust_type3_p5=zeros(1,10000);
            thrust_type3_p6=zeros(1,10000);

            thrust_type4_p1=zeros(1,10000);
            thrust_type4_p2=zeros(1,10000);
            thrust_type4_p3=zeros(1,10000);
            thrust_type4_p4=zeros(1,10000);
            thrust_type4_p5=zeros(1,10000);
            thrust_type4_p6=zeros(1,10000);


            thrust_type5_p1=zeros(1,10000);
            thrust_type5_p2=zeros(1,10000);
            thrust_type5_p3=zeros(1,10000);
            thrust_type5_p4=zeros(1,10000);
            thrust_type5_p5=zeros(1,10000);
            thrust_type5_p6=zeros(1,10000);

            format longG;

            i=1;
            G=9.81;

            total_rocket_mass=(sum(phase_data(2,:))*rocket_data(1,1))+(sum(phase_data(3,:))*rocket_data(2,1))+(sum(phase_data(4,:))*rocket_data(3,1))+(sum(phase_data(5,:))*rocket_data(4,1))+(sum(phase_data(6,:))*rocket_data(5,1));
            total_initial_mass=mass_of_clamp+mass_of_payload+total_rocket_mass;

            velocity(i)=initial_velocity;
            vel_mach(i)=initial_velocity/343;
            current_time(i)=0;
            current_pos(i)=initial_distance;
            current_mass(i)=total_initial_mass;

            while (velocity(i)>=0 || i<10001)
                i=i+1;

                %updating_time
                current_time(i)=current_time(i-1)+delta_t;

                %updating_velocity
                velocity(i)=(velocity(i-1)+(acc(i-1)*delta_t));

                %updating_position
                current_pos(i)=((current_pos(i-1))+(velocity(i-1)*delta_t)+(0.5*acc(i-1)*power((delta_t),2)));

                %updating_mass and thrust
                time_p1(i)=current_time(i);

                %phase_1

                %type1
                if time_p1(i)>=rocket_data(1,4)
                    mp_type1_p1(i)=rocket_data(1,2)*phase_data(2,1);
                else
                    mp_type1_p1(i)=(rocket_data(1,2)*phase_data(2,1)/rocket_data(1,4))*time_p1(i);
                end

                if (mp_type1_p1(i)==0 || (mp_type1_p1(i)-mp_type1_p1(i-1))==0)
                    thrust_type1_p1(i)=0;
                else
                    RocketTime=ProfileRocketTime.grad((app.type*7+1):(app.type*7+7));
                    RocketThrust=ProfileRocketThrust.grad((app.type*7+1):(app.type*7+7));
                    if time_p1(i)<=RocketTime(2)
                        thrust_type1_p1(i)=phase_data(2,1)*(RocketThrust(2)-((RocketThrust(2)-RocketThrust(1))/(RocketTime(1)^2-RocketTime(2)^2+2*RocketTime (2)*(RocketTime (2)-RocketTime(1))))*((time_p1(i)^2)-2*RocketTime(2)*time_p1(i)+RocketTime(2)^2));
                    elseif time_p1(i) <=RocketTime (3)
                        thrust_type1_p1(i)=phase_data(2,1)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^3- (RocketTime (2) - RocketTime (3))^3)) * ((time_p1(i)-RocketTime (3))^3-(RocketTime (2) - RocketTime (3))^3)));
                    elseif time_p1(i)<=RocketTime (4)
                        thrust_type1_p1(i)=phase_data(2,1)*(RocketThrust (4) + ((RocketThrust (4) -RocketThrust (3)) * (time_p1(i)-RocketTime (4))/(RocketTime (4) -RocketTime (3))));
                    elseif time_p1(i)<=RocketTime (5)
                        thrust_type1_p1(i)=phase_data(2,1)*(RocketThrust (5) + ((RocketThrust (5)-RocketThrust (4)) * (time_p1(i)-RocketTime (5))/(RocketTime (5) -RocketTime (4))));
                    elseif time_p1(i)<=RocketTime (6)
                        thrust_type1_p1(i)=phase_data(2,1)*(RocketThrust (6) + ((RocketThrust (6)-RocketThrust (5)) * (time_p1(i)-RocketTime (6))/(RocketTime (6) -RocketTime (5))));
                    elseif time_p1(i)<=RocketTime (7)
                        thrust_type1_p1(i)=phase_data(2,1)*(RocketThrust (6) + (((RocketThrust (7) -RocketThrust (6))/((RocketTime (7) -RocketTime (7))^3-(RocketTime (6) - RocketTime (7))^3)) * ((time_p1(i)-RocketTime (7))^3- (RocketTime (6) - RocketTime (7))^3)));
                    else
                        thrust_type1_p1(i)=0;
                    end
                end

                %type2
                if time_p1(i)>=rocket_data(2,4)
                    mp_type2_p1(i)=rocket_data(2,2)*phase_data(3,1);
                else
                    mp_type2_p1(i)=(rocket_data(2,2)*phase_data(3,1)/rocket_data(2,4))*time_p1(i);
                end

                if (mp_type2_p1(i)==0 || (mp_type2_p1(i)-mp_type2_p1(i-1)==0))
                    thrust_type2_p1(i)=0;
                else
                    thrust_type2_p1(i)=phase_data(3,1)*rocket_data(2,5);
                end

                %type3
                if time_p1(i)>=rocket_data(3,4)
                    mp_type3_p1(i)=rocket_data(3,2)*phase_data(4,1);
                else
                    mp_type3_p1(i)=(rocket_data(3,2)*phase_data(4,1)/rocket_data(3,4))*time_p1(i);
                end

                if (mp_type3_p1(i)==0 || (mp_type3_p1(i)-mp_type3_p1(i-1))==0)
                    thrust_type3_p1(i)=0;
                else
                    thrust_type3_p1(i)=phase_data(4,1)*rocket_data(3,5);
                end

                %type4
                if time_p1(i)>=rocket_data(4,4)
                    mp_type4_p1(i)=rocket_data(4,2)*phase_data(5,1);
                else
                    mp_type4_p1(i)=(rocket_data(4,2)*phase_data(5,1)/rocket_data(4,4))*time_p1(i);
                end

                if (mp_type4_p1(i)==0 || (mp_type4_p1(i)-mp_type4_p1(i-1))==0)
                    thrust_type4_p1(i)=0;
                else
                    thrust_type4_p1(i)=phase_data(5,1)*rocket_data(4,5);
                end

                %type5
                if time_p1(i)>=rocket_data(5,4)
                    mp_type5_p1(i)=rocket_data(5,2)*phase_data(6,1);
                else
                    mp_type5_p1(i)=(rocket_data(5,2)*phase_data(6,1)/rocket_data(5,4))*time_p1(i);
                end

                if (mp_type5_p1(i)==0 || (mp_type5_p1(i)-mp_type5_p1(i-1))==0)
                    thrust_type5_p1(i)=0;
                else
                    RocketTime=ProfileRocketTime.rrm1((app.type*7+1):(app.type*7+7));
                    RocketThrust=ProfileRocketThrust.rrm1((app.type*7+1):(app.type*7+7));
                    if time_p1(i)<=RocketTime(2)
                        thrust_type5_p1(i)=phase_data(6,1)*(RocketThrust(1)+(((RocketThrust(2)-RocketThrust(1))/(RocketTime(2)^3-RocketTime(1)^3))*(time_p1(i)^3-RocketTime(1)^3)));
                    elseif time_p1(i) <=RocketTime (3)
                        thrust_type5_p1(i)=phase_data(6,1)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^2- (RocketTime (2) - RocketTime (3))^2)) * ((time_p1(i)-RocketTime (3))^2-(RocketTime (2) - RocketTime (3))^2)));
                    elseif time_p1(i)<=RocketTime (4)
                        thrust_type5_p1(i)=phase_data(6,1)*(RocketThrust (3) + (((RocketThrust (4) -RocketThrust (3))/(RocketThrust(4)^3 -RocketThrust(3)^3))*(time_p1(i)^3-RocketTime(3)^3)));
                    elseif time_p1(i)<=RocketTime (5)
                        thrust_type5_p1(i)=phase_data(6,1)*( RocketThrust (4) + (((RocketThrust (5) -RocketThrust (4))/(RocketThrust(5)^3 -RocketThrust(4)^3))*(time_p1(i)^3-RocketTime(4)^3)));
                    elseif time_p1(i)<=RocketTime (6)
                        thrust_type5_p1(i)=phase_data(6,1)*(RocketThrust (5) + (((RocketThrust (6) -RocketThrust (5))/(RocketThrust(6)^3 -RocketThrust(5)^3))*(time_p1(i)^3-RocketTime(5)^3)));
                    elseif time_p1(i)<=RocketTime (7)
                        thrust_type5_p1(i)=phase_data(6,1)*(RocketThrust (6) + (((RocketThrust (7)-RocketThrust (6))/((RocketTime (7) -RocketTime (7))^2- (RocketTime (6) - RocketTime (7))^2)) * ((time_p1(i)-RocketTime (7))^2-(RocketTime (6) - RocketTime (7))^2)));
                    else
                        thrust_type5_p1(i)=0;
                    end
                end


                %phase_2
                if current_pos(i)<=phase_data(1,2)
                    time_p2(i)=0;
                else
                    time_p2(i)=time_p2(i-1)+delta_t;
                    %type1
                    if time_p2(i)>=rocket_data(1,4)
                        mp_type1_p2(i)=rocket_data(1,2)*phase_data(2,2);
                    else
                        mp_type1_p2(i)=(rocket_data(1,2)*phase_data(2,2)/rocket_data(1,4))*time_p2(i);
                    end

                    if (mp_type1_p2(i)==0 || (mp_type1_p2(i)-mp_type1_p2(i-1))==0)
                        thrust_type1_p2(i)=0;
                    else
                        RocketTime=ProfileRocketTime.grad((app.type*7+1):(app.type*7+7));
                        RocketThrust=ProfileRocketThrust.grad((app.type*7+1):(app.type*7+7));
                        if time_p2(i)<=RocketTime(2)
                            thrust_type1_p2(i)=phase_data(2,2)*(RocketThrust(2)-((RocketThrust(2)-RocketThrust(1))/(RocketTime(1)^2-RocketTime(2)^2+2*RocketTime (2)*(RocketTime (2)-RocketTime(1))))*((time_p2(i)^2)-2*RocketTime(2)*time_p2(i)+RocketTime(2)^2));
                        elseif time_p2(i) <=RocketTime (3)
                            thrust_type1_p2(i)=phase_data(2,2)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^3- (RocketTime (2) - RocketTime (3))^3)) * ((time_p2(i)-RocketTime (3))^3-(RocketTime (2) - RocketTime (3))^3)));
                        elseif time_p2(i)<=RocketTime (4)
                            thrust_type1_p2(i)=phase_data(2,2)*(RocketThrust (4) + ((RocketThrust (4) -RocketThrust (3)) * (time_p2(i)-RocketTime (4))/(RocketTime (4) -RocketTime (3))));
                        elseif time_p2(i)<=RocketTime (5)
                            thrust_type1_p2(i)=phase_data(2,2)*( RocketThrust (5) + ((RocketThrust (5)-RocketThrust (4)) * (time_p2(i)-RocketTime (5))/(RocketTime (5) -RocketTime (4))));
                        elseif time_p2(i)<=RocketTime (6)
                            thrust_type1_p2(i)=phase_data(2,2)*(RocketThrust (6) + ((RocketThrust (6)-RocketThrust (5)) * (time_p2(i)-RocketTime (6))/(RocketTime (6) -RocketTime (5))));
                        elseif time_p2(i)<=RocketTime (7)
                            thrust_type1_p2(i)=phase_data(2,2)*(RocketThrust (6) + (((RocketThrust (7) -RocketThrust (6))/((RocketTime (7) -RocketTime (7))^3-(RocketTime (6) - RocketTime (7))^3)) * ((time_p2(i)-RocketTime (7))^3- (RocketTime (6) - RocketTime (7))^3)));
                        else
                            thrust_type1_p2(i)=0;
                        end
                    end

                    %type2
                    if time_p2(i)>=rocket_data(2,4)
                        mp_type2_p2(i)=rocket_data(2,2)*phase_data(3,2);
                    else
                        mp_type2_p2(i)=(rocket_data(2,2)*phase_data(3,2)/rocket_data(2,4))*time_p2(i);
                    end

                    if (mp_type2_p2(i)==0 || (mp_type2_p2(i)-mp_type2_p2(i-1))==0)
                        thrust_type2_p2(i)=0;
                    else
                        thrust_type2_p2(i)=rocket_data(2,5)*phase_data(3,2);
                    end

                    %type3
                    if time_p2(i)>=rocket_data(3,4)
                        mp_type3_p2(i)=rocket_data(3,2)*phase_data(4,2);
                    else
                        mp_type3_p2(i)=(rocket_data(3,2)*phase_data(4,2)/rocket_data(3,4))*time_p2(i);
                    end

                    if (mp_type3_p2(i)==0 || (mp_type3_p2(i)-mp_type3_p2(i-1))==0)
                        thrust_type3_p2(i)=0;
                    else
                        thrust_type3_p2(i)=rocket_data(3,5)*phase_data(4,2);
                    end

                    %type4
                    if time_p2(i)>=rocket_data(4,4)
                        mp_type4_p2(i)=rocket_data(4,2)*phase_data(5,2);
                    else
                        mp_type4_p2(i)=(rocket_data(1,2)*phase_data(5,2)/rocket_data(4,4))*time_p2(i);
                    end

                    if (mp_type4_p2(i)==0 || (mp_type4_p2(i)-mp_type4_p2(i-1))==0)
                        thrust_type4_p2(i)=0;
                    else
                        thrust_type4_p2(i)=rocket_data(4,5)*phase_data(6,2);
                    end

                    %type5
                    if time_p2(i)>=rocket_data(5,4)
                        mp_type5_p2(i)=rocket_data(5,2)*phase_data(6,2);
                    else
                        mp_type5_p2(i)=(rocket_data(5,2)*phase_data(6,2)/rocket_data(5,4))*time_p2(i);
                    end

                    if (mp_type5_p2(i)==0 || (mp_type5_p2(i)-mp_type5_p2(i-1))==0)
                        thrust_type5_p2(i)=0;
                    else
                        RocketTime=ProfileRocketTime.rrm1((app.type*7+1):(app.type*7+7));
                        RocketThrust=ProfileRocketThrust.rrm1((app.type*7+1):(app.type*7+7));
                        if time_p2(i)<=RocketTime(2)
                            thrust_type5_p2(i)=phase_data(6,2)*(RocketThrust(1)+(((RocketThrust(2)-RocketThrust(1))/(RocketTime(2)^3-RocketTime(1)^3))*(time_p2(i)^3-RocketTime(1)^3)));
                        elseif time_p2(i)<=RocketTime (3)
                            thrust_type5_p2(i)=phase_data(6,2)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^2- (RocketTime (2) - RocketTime (3))^2)) * ((time_p2(i)-RocketTime (3))^2-(RocketTime (2) - RocketTime (3))^2)));
                        elseif time_p2(i)<=RocketTime (4)
                            thrust_type5_p2(i)=phase_data(6,2)*(RocketThrust (3) + (((RocketThrust (4) -RocketThrust (3))/(RocketThrust(4)^3 -RocketThrust(3)^3))*(time_p2(i)^3-RocketTime(3)^3)));
                        elseif time_p2(i)<=RocketTime (5)
                            thrust_type5_p2(i)=phase_data(6,2)*( RocketThrust (4) + (((RocketThrust (5) -RocketThrust (4))/(RocketThrust(5)^3 -RocketThrust(4)^3))*(time_p2(i)^3-RocketTime(4)^3)));
                        elseif time_p2(i)<=RocketTime (6)
                            thrust_type5_p2(i)=phase_data(6,2)*(RocketThrust (5) + (((RocketThrust (6) -RocketThrust (5))/(RocketThrust(6)^3 -RocketThrust(5)^3))*(time_p2(i)^3-RocketTime(5)^3)));
                        elseif time_p2(i)<=RocketTime (7)
                            thrust_type5_p2(i)=phase_data(6,2)*(RocketThrust (6) + (((RocketThrust (7)-RocketThrust (6))/((RocketTime (7) -RocketTime (7))^2- (RocketTime (6) - RocketTime (7))^2)) * ((time_p2(i)-RocketTime (7))^2-(RocketTime (6) - RocketTime (7))^2)));
                        else
                            thrust_type5_p2(i)=0;
                        end
                    end
                end

                %phase_3
                if current_pos(i)<=phase_data(1,3)
                    time_p3(i)=0;
                else
                    time_p3(i)=time_p3(i-1)+delta_t;
                    %type1
                    if time_p3(i)>=rocket_data(1,4)
                        mp_type1_p3(i)=rocket_data(1,2)*phase_data(2,3);
                    else
                        mp_type1_p3(i)=(rocket_data(1,2)*phase_data(2,3)/rocket_data(1,4))*time_p3(i);
                    end

                    if (mp_type1_p3(i)==0 || (mp_type1_p3(i)-mp_type1_p3(i-1))==0)
                        thrust_type1_p3(i)=0;
                    else
                        RocketTime=ProfileRocketTime.grad((app.type*7+1):(app.type*7+7));
                        RocketThrust=ProfileRocketThrust.grad((app.type*7+1):(app.type*7+7));
                        if time_p3(i)<=RocketTime(2)
                            thrust_type1_p3(i)=phase_data(2,3)*(RocketThrust(2)-((RocketThrust(2)-RocketThrust(1))/(RocketTime(1)^2-RocketTime(2)^2+2*RocketTime (2)*(RocketTime (2)-RocketTime(1))))*((time_p3(i)^2)-2*RocketTime(2)*time_p3(i)+RocketTime(2)^2));
                        elseif time_p3(i)<=RocketTime (3)
                            thrust_type1_p3(i)=phase_data(2,3)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^3- (RocketTime (2) - RocketTime (3))^3)) * ((time_p3(i)-RocketTime (3))^3-(RocketTime (2) - RocketTime (3))^3)));
                        elseif time_p3(i)<=RocketTime (4)
                            thrust_type1_p3(i)=phase_data(2,3)*(RocketThrust (4) + ((RocketThrust (4) -RocketThrust (3)) * (time_p3(i)-RocketTime (4))/(RocketTime (4) -RocketTime (3))));
                        elseif time_p3(i)<=RocketTime (5)
                            thrust_type1_p3(i)=phase_data(2,3)*( RocketThrust (5) + ((RocketThrust (5)-RocketThrust (4)) * (time_p3(i)-RocketTime (5))/(RocketTime (5) -RocketTime (4))));
                        elseif time_p3(i)<=RocketTime (6)
                            thrust_type1_p3(i)=phase_data(2,3)*(RocketThrust (6) + ((RocketThrust (6)-RocketThrust (5)) * (time_p3(i)-RocketTime (6))/(RocketTime (6) -RocketTime (5))));
                        elseif time_p3(i)<=RocketTime (7)
                            thrust_type1_p3(i)=phase_data(2,3)*(RocketThrust (6) + (((RocketThrust (7) -RocketThrust (6))/((RocketTime (7) -RocketTime (7))^3-(RocketTime (6) - RocketTime (7))^3)) * ((time_p3(i)-RocketTime (7))^3- (RocketTime (6) - RocketTime (7))^3)));
                        else
                            thrust_type1_p3(i)=0;
                        end
                    end

                    %type2
                    if time_p3(i)>=rocket_data(2,4)
                        mp_type2_p3(i)=rocket_data(2,2)*phase_data(3,3);
                    else
                        mp_type2_p3(i)=(rocket_data(2,2)*phase_data(3,3)/rocket_data(2,4))*time_p3(i);
                    end

                    if (mp_type2_p3(i)==0 || (mp_type2_p3(i)-mp_type2_p3(i-1))==0)
                        thrust_type2_p3(i)=0;
                    else
                        thrust_type2_p3(i)=rocket_data(2,5)*phase_data(3,3);
                    end

                    %type3
                    if time_p3(i)>=rocket_data(3,4)
                        mp_type3_p3(i)=rocket_data(3,2)*phase_data(4,3);
                    else
                        mp_type3_p3(i)=(rocket_data(3,2)*phase_data(4,3)/rocket_data(3,4))*time_p3(i);
                    end

                    if (mp_type3_p3(i)==0 || (mp_type3_p3(i)-mp_type3_p3(i-1))==0)
                        thrust_type3_p3(i)=0;
                    else
                        thrust_type3_p3(i)=rocket_data(3,5)*phase_data(4,3);
                    end

                    %type4
                    if time_p3(i)>=rocket_data(4,4)
                        mp_type4_p3(i)=rocket_data(4,2)*phase_data(5,3);
                    else
                        mp_type4_p3(i)=(rocket_data(4,2)*phase_data(5,3)/rocket_data(4,4))*time_p3(i);
                    end

                    if (mp_type4_p3(i)==0 || (mp_type4_p3(i)-mp_type4_p3(i-1))==0)
                        thrust_type4_p3(i)=0;
                    else
                        thrust_type4_p3(i)=rocket_data(4,5)*phase_data(5,3);
                    end

                    %type5
                    if time_p3(i)>=rocket_data(5,4)
                        mp_type5_p3(i)=rocket_data(5,2)*phase_data(6,3);
                    else
                        mp_type5_p3(i)=(rocket_data(5,2)*phase_data(6,3)/rocket_data(5,4))*time_p3(i);
                    end

                    if (mp_type5_p3(i)==0 || (mp_type5_p3(i)-mp_type5_p3(i-1))==0)
                        thrust_type5_p3(i)=0;
                    else
                        RocketTime=ProfileRocketTime.rrm1((app.type*7+1):(app.type*7+7));
                        RocketThrust=ProfileRocketThrust.rrm1((app.type*7+1):(app.type*7+7));
                        if time_p3(i)<=RocketTime(2)
                            thrust_type5_p3(i)=phase_data(6,3)*(RocketThrust(1)+(((RocketThrust(2)-RocketThrust(1))/(RocketTime(2)^3-RocketTime(1)^3))*(time_p3(i)^3-RocketTime(1)^3)));
                        elseif time_p3(i)<=RocketTime (3)
                            thrust_type5_p3(i)=phase_data(6,3)*( RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^2- (RocketTime (2) - RocketTime (3))^2)) * ((time_p3(i)-RocketTime (3))^2-(RocketTime (2) - RocketTime (3))^2)));
                        elseif time_p3(i)<=RocketTime (4)
                            thrust_type5_p3(i)=phase_data(6,3)*(RocketThrust (3) + (((RocketThrust (4) -RocketThrust (3))/(RocketThrust(4)^3 -RocketThrust(3)^3))*(time_p3(i)^3-RocketTime(3)^3)));
                        elseif time_p3(i)<=RocketTime (5)
                            thrust_type5_p3(i)=phase_data(6,3)*(RocketThrust (4) + (((RocketThrust (5) -RocketThrust (4))/(RocketThrust(5)^3 -RocketThrust(4)^3))*(time_p3(i)^3-RocketTime(4)^3)));
                        elseif time_p3(i)<=RocketTime (6)
                            thrust_type5_p3(i)=phase_data(6,3)*(RocketThrust (5) + (((RocketThrust (6) -RocketThrust (5))/(RocketThrust(6)^3 -RocketThrust(5)^3))*(time_p3(i)^3-RocketTime(5)^3)));
                        elseif time_p3(i)<=RocketTime (7)
                            thrust_type5_p3(i)=phase_data(6,3)*(RocketThrust (6) + (((RocketThrust (7)-RocketThrust (6))/((RocketTime (7) -RocketTime (7))^2- (RocketTime (6) - RocketTime (7))^2)) * ((time_p3(i)-RocketTime (7))^2-(RocketTime (6) - RocketTime (7))^2)));
                        else
                            thrust_type5_p3(i)=0;
                        end
                    end
                end

                %phase_4
                if current_pos(i)<=phase_data(1,4)
                    time_p4(i)=0;
                else
                    time_p4(i)=time_p4(i-1)+delta_t;
                    %type1
                    if time_p4(i)>=rocket_data(1,4)
                        mp_type1_p4(i)=rocket_data(1,2)*phase_data(2,4);
                    else
                        mp_type1_p4(i)=(rocket_data(1,2)*phase_data(2,4)/rocket_data(1,4))*time_p4(i);
                    end

                    if (mp_type1_p4(i)==0 || (mp_type1_p4(i)-mp_type1_p4(i-1))==0)
                        thrust_type1_p4(i)=0;
                    else
                        RocketTime=ProfileRocketTime.grad((app.type*7+1):(app.type*7+7));
                        RocketThrust=ProfileRocketThrust.grad((app.type*7+1):(app.type*7+7));
                        if time_p4(i)<=RocketTime(2)
                            thrust_type1_p4(i)=phase_data(2,4)*(RocketThrust(2)-((RocketThrust(2)-RocketThrust(1))/(RocketTime(1)^2-RocketTime(2)^2+2*RocketTime (2)*(RocketTime (2)-RocketTime(1))))*((time_p4(i)^2)-2*RocketTime(2)*time_p4(i)+RocketTime(2)^2));
                        elseif time_p4(i)<=RocketTime (3)
                            thrust_type1_p4(i)=phase_data(2,4)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^3- (RocketTime (2) - RocketTime (3))^3)) * ((time_p4(i)-RocketTime (3))^3-(RocketTime (2) - RocketTime (3))^3)));
                        elseif time_p4(i)<=RocketTime (4)
                            thrust_type1_p4(i)=phase_data(2,4)*(RocketThrust (4) + ((RocketThrust (4) -RocketThrust (3)) * (time_p4(i)-RocketTime (4))/(RocketTime (4) -RocketTime (3))));
                        elseif time_p4(i)<=RocketTime (5)
                            thrust_type1_p4(i)=phase_data(2,4)*( RocketThrust (5) + ((RocketThrust (5)-RocketThrust (4)) * (time_p4(i)-RocketTime (5))/(RocketTime (5) -RocketTime (4))));
                        elseif time_p4(i)<=RocketTime (6)
                            thrust_type1_p4(i)=phase_data(2,4)*(RocketThrust (6) + ((RocketThrust (6)-RocketThrust (5)) * (time_p4(i)-RocketTime (6))/(RocketTime (6) -RocketTime (5))));
                        elseif time_p4(i)<=RocketTime (7)
                            thrust_type1_p4(i)=phase_data(2,4)*( RocketThrust (6) + (((RocketThrust (7) -RocketThrust (6))/((RocketTime (7) -RocketTime (7))^3-(RocketTime (6) - RocketTime (7))^3)) * ((time_p4(i)-RocketTime (7))^3- (RocketTime (6) - RocketTime (7))^3)));
                        else
                            thrust_type1_p4(i)=0;
                        end
                    end

                    %type2
                    if time_p4(i)>=rocket_data(2,4)
                        mp_type2_p4(i)=rocket_data(2,2)*phase_data(3,4);
                    else
                        mp_type2_p4(i)=(rocket_data(2,2)*phase_data(3,4)/rocket_data(2,4))*time_p4(i);
                    end

                    if (mp_type2_p4(i)==0 || (mp_type2_p4(i)-mp_type2_p4(i-1))==0)
                        thrust_type2_p4(i)=0;
                    else
                        thrust_type2_p4(i)=rocket_data(2,5)*phase_data(3,4);
                    end

                    %type3
                    if time_p4(i)>=rocket_data(3,4)
                        mp_type3_p4(i)=rocket_data(3,2)*phase_data(4,4);
                    else
                        mp_type3_p4(i)=(rocket_data(3,2)*phase_data(4,4)/rocket_data(3,4))*time_p4(i);
                    end

                    if (mp_type3_p4(i)==0 || (mp_type3_p4(i)-mp_type3_p4(i-1))==0)
                        thrust_type3_p4(i)=0;
                    else
                        thrust_type3_p4(i)=rocket_data(3,5)*phase_data(4,4);
                    end

                    %type4
                    if time_p4(i)>=rocket_data(4,4)
                        mp_type4_p4(i)=rocket_data(4,2)*phase_data(5,4);
                    else
                        mp_type4_p4(i)=(rocket_data(4,2)*phase_data(5,4)/rocket_data(4,4))*time_p4(i);
                    end

                    if (mp_type4_p4(i)==0 || (mp_type4_p4(i)-mp_type4_p4(i-1))==0)
                        thrust_type4_p4(i)=0;
                    else
                        thrust_type4_p4(i)=rocket_data(4,5)*phase_data(5,4);
                    end

                    %type5
                    if time_p4(i)>=rocket_data(5,4)
                        mp_type5_p4(i)=rocket_data(5,2)*phase_data(6,4);
                    else
                        mp_type5_p4(i)=(rocket_data(5,2)*phase_data(6,4)/rocket_data(5,4))*time_p4(i);
                    end

                    if (mp_type5_p4(i)==0 || (mp_type5_p4(i)-mp_type5_p4(i-1))==0)
                        thrust_type5_p4(i)=0;
                    else
                        RocketTime=ProfileRocketTime.rrm1((app.type*7+1):(app.type*7+7));
                        RocketThrust=ProfileRocketThrust.rrm1((app.type*7+1):(app.type*7+7));
                        if time_p4(i)<=RocketTime(2)
                            thrust_type5_p4(i)=phase_data(6,4)*(RocketThrust(1)+(((RocketThrust(2)-RocketThrust(1))/(RocketTime(2)^3-RocketTime(1)^3))*(time_p4(i)^3-RocketTime(1)^3)));
                        elseif time_p4(i)<=RocketTime (3)
                            thrust_type5_p4(i)=phase_data(6,4)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^2- (RocketTime (2) - RocketTime (3))^2)) * ((time_p4(i)-RocketTime (3))^2-(RocketTime (2) - RocketTime (3))^2)));
                        elseif time_p4(i)<=RocketTime (4)
                            thrust_type5_p4(i)=phase_data(6,4)*(RocketThrust (3) + (((RocketThrust (4) -RocketThrust (3))/(RocketThrust(4)^3 -RocketThrust(3)^3))*(time_p4(i)^3-RocketTime(3)^3)));
                        elseif time_p4(i)<=RocketTime (5)
                            thrust_type5_p4(i)=phase_data(6,4)*(RocketThrust (4) + (((RocketThrust (5) -RocketThrust (4))/(RocketThrust(5)^3 -RocketThrust(4)^3))*(time_p4(i)^3-RocketTime(4)^3)));
                        elseif time_p4(i)<=RocketTime (6)
                            thrust_type5_p4(i)=phase_data(6,4)*(RocketThrust (5) + (((RocketThrust (6) -RocketThrust (5))/(RocketThrust(6)^3 -RocketThrust(5)^3))*(time_p4(i)^3-RocketTime(5)^3)));
                        elseif time_p4(i)<=RocketTime (7)
                            thrust_type5_p4(i)=phase_data(6,4)*(RocketThrust (6) + (((RocketThrust (7)-RocketThrust (6))/((RocketTime (7) -RocketTime (7))^2- (RocketTime (6) - RocketTime (7))^2)) * ((time_p4(i)-RocketTime (7))^2-(RocketTime (6) - RocketTime (7))^2)));
                        else
                            thrust_type5_p4(i)=0;
                        end
                    end
                end

                %phase_5
                if current_pos(i)<=phase_data(1,5)
                    time_p5(i)=0;
                else
                    time_p5(i)=time_p5(i-1)+delta_t;
                    %type1
                    if time_p5(i)>=rocket_data(1,4)
                        mp_type1_p5(i)=rocket_data(1,2)*phase_data(2,5);
                    else
                        mp_type1_p5(i)=(rocket_data(1,2)*phase_data(2,5)/rocket_data(1,4))*time_p5(i);
                    end

                    if (mp_type1_p5(i)==0 || (mp_type1_p5(i)-mp_type1_p5(i-1))==0)
                        thrust_type1_p5(i)=0;
                    else
                        RocketTime=ProfileRocketTime.grad((app.type*7+1):(app.type*7+7));
                        RocketThrust=ProfileRocketThrust.grad((app.type*7+1):(app.type*7+7));
                        if time_p5(i)<=RocketTime(2)
                            thrust_type1_p5(i)=phase_data(2,5)*(RocketThrust(2)-((RocketThrust(2)-RocketThrust(1))/(RocketTime(1)^2-RocketTime(2)^2+2*RocketTime (2)*(RocketTime (2)-RocketTime(1))))*((time_p5(i)^2)-2*RocketTime(2)*time_p5(i)+RocketTime(2)^2));
                        elseif time_p5(i)<=RocketTime (3)
                            thrust_type1_p5(i)=phase_data(2,5)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^3- (RocketTime (2) - RocketTime (3))^3)) * ((time_p5(i)-RocketTime (3))^3-(RocketTime (2) - RocketTime (3))^3)));
                        elseif time_p5(i)<=RocketTime (4)
                            thrust_type1_p5(i)=phase_data(2,5)*(RocketThrust (4) + ((RocketThrust (4) -RocketThrust (3)) * (time_p5(i)-RocketTime (4))/(RocketTime (4) -RocketTime (3))));
                        elseif time_p5(i)<=RocketTime (5)
                            thrust_type1_p5(i)=phase_data(2,5)*(RocketThrust (5) + ((RocketThrust (5)-RocketThrust (4)) * (time_p5(i)-RocketTime (5))/(RocketTime (5) -RocketTime (4))));
                        elseif time_p5(i)<=RocketTime (6)
                            thrust_type1_p5(i)=phase_data(2,5)*(RocketThrust (6) + ((RocketThrust (6)-RocketThrust (5)) * (time_p5(i)-RocketTime (6))/(RocketTime (6) -RocketTime (5))));
                        elseif time_p5(i)<=RocketTime (7)
                            thrust_type1_p5(i)=phase_data(2,5)*(RocketThrust (6) + (((RocketThrust (7) -RocketThrust (6))/((RocketTime (7) -RocketTime (7))^3-(RocketTime (6) - RocketTime (7))^3)) * ((time_p5(i)-RocketTime (7))^3- (RocketTime (6) - RocketTime (7))^3)));
                        else
                            thrust_type1_p5(i)=0;
                        end
                    end

                    %type2
                    if time_p5(i)>=rocket_data(2,4)
                        mp_type2_p5(i)=rocket_data(2,2)*phase_data(3,5);
                    else
                        mp_type2_p5(i)=(rocket_data(2,2)*phase_data(3,5)/rocket_data(2,4))*time_p5(i);
                    end

                    if (mp_type2_p5(i)==0 || (mp_type2_p5(i)-mp_type2_p5(i-1))==0)
                        thrust_type2_p5(i)=0;
                    else
                        thrust_type2_p5(i)=rocket_data(2,5)*phase_data(3,5);
                    end

                    %type3
                    if time_p5(i)>=rocket_data(3,4)
                        mp_type3_p5(i)=rocket_data(3,2)*phase_data(4,5);
                    else
                        mp_type3_p5(i)=(rocket_data(3,2)*phase_data(4,5)/rocket_data(3,4))*time_p5(i);
                    end

                    if (mp_type3_p5(i)==0 || (mp_type3_p5(i)-mp_type3_p5(i-1))==0)
                        thrust_type3_p5(i)=0;
                    else
                        thrust_type3_p5(i)=rocket_data(3,5)*phase_data(4,5);
                    end

                    %type4
                    if time_p5(i)>=rocket_data(4,4)
                        mp_type4_p5(i)=rocket_data(4,2)*phase_data(5,5);
                    else
                        mp_type4_p5(i)=(rocket_data(4,2)*phase_data(5,5)/rocket_data(4,4))*time_p5(i);
                    end

                    if (mp_type4_p5(i)==0 || (mp_type4_p5(i)-mp_type4_p5(i-1))==0)
                        thrust_type4_p5(i)=0;
                    else
                        thrust_type4_p5(i)=rocket_data(4,5)*phase_data(5,5);
                    end

                    %type5
                    if time_p5(i)>=rocket_data(5,4)
                        mp_type5_p5(i)=rocket_data(5,2)*phase_data(6,5);
                    else
                        mp_type5_p5(i)=(rocket_data(5,2)*phase_data(6,5)/rocket_data(5,4))*time_p5(i);
                    end

                    if (mp_type5_p5(i)==0 || (mp_type5_p5(i)-mp_type5_p5(i-1))==0)
                        thrust_type5_p5(i)=0;
                    else
                        RocketTime=ProfileRocketTime.rrm1((app.type*7+1):(app.type*7+7));
                        RocketThrust=ProfileRocketThrust.rrm1((app.type*7+1):(app.type*7+7));
                        if time_p5(i)<=RocketTime(2)
                            thrust_type5_p5(i)=phase_data(6,5)*(RocketThrust(1)+(((RocketThrust(2)-RocketThrust(1))/(RocketTime(2)^3-RocketTime(1)^3))*(time_p5(i)^3-RocketTime(1)^3)));
                        elseif time_p5(i)<=RocketTime (3)
                            thrust_type5_p5(i)=phase_data(6,5)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^2- (RocketTime (2) - RocketTime (3))^2)) * ((time_p5(i)-RocketTime (3))^2-(RocketTime (2) - RocketTime (3))^2)));
                        elseif time_p5(i)<=RocketTime (4)
                            thrust_type5_p5(i)=phase_data(6,5)*(RocketThrust (3) + (((RocketThrust (4) -RocketThrust (3))/(RocketThrust(4)^3 -RocketThrust(3)^3))*(time_p5(i)^3-RocketTime(3)^3)));
                        elseif time_p5(i)<=RocketTime (5)
                            thrust_type5_p5(i)=phase_data(6,5)*(RocketThrust (4) + (((RocketThrust (5) -RocketThrust (4))/(RocketThrust(5)^3 -RocketThrust(4)^3))*(time_p5(i)^3-RocketTime(4)^3)));
                        elseif time_p5(i)<=RocketTime (6)
                            thrust_type5_p5(i)=phase_data(6,5)*( RocketThrust (5) + (((RocketThrust (6) -RocketThrust (5))/(RocketThrust(6)^3 -RocketThrust(5)^3))*(time_p5(i)^3-RocketTime(5)^3)));
                        elseif time_p5(i)<=RocketTime (7)
                            thrust_type5_p5(i)=phase_data(6,5)*( RocketThrust (6) + (((RocketThrust (7)-RocketThrust (6))/((RocketTime (7) -RocketTime (7))^2- (RocketTime (6) - RocketTime (7))^2)) * ((time_p5(i)-RocketTime (7))^2-(RocketTime (6) - RocketTime (7))^2)));
                        else
                            thrust_type5_p5(i)=0;
                        end
                    end
                end

                %phase_6
                if current_pos(i)<=phase_data(1,6)
                    time_p6(i)=0;
                else
                    time_p6(i)=time_p6(i-1)+delta_t;
                    %type1
                    if time_p6(i)>=rocket_data(1,4)
                        mp_type1_p6(i)=rocket_data(1,2)*phase_data(2,6);
                    else
                        mp_type1_p6(i)=(rocket_data(1,2)*phase_data(2,6)/rocket_data(1,4))*time_p6(i);
                    end

                    if (mp_type1_p6(i)==0 || (mp_type1_p6(i)-mp_type1_p6(i-1))==0)
                        thrust_type1_p6(i)=0;
                    else
                        RocketTime=ProfileRocketTime.grad((app.type*7+1):(app.type*7+7));
                        RocketThrust=ProfileRocketThrust.grad((app.type*7+1):(app.type*7+7));
                        if time_p6(i)<=RocketTime(2)
                            thrust_type1_p6(i)=phase_data(2,6)*(RocketThrust(2)-((RocketThrust(2)-RocketThrust(1))/(RocketTime(1)^2-RocketTime(2)^2+2*RocketTime (2)*(RocketTime (2)-RocketTime(1))))*((time_p6(i)^2)-2*RocketTime(2)*time_p6(i)+RocketTime(2)^2));
                        elseif time_p6(i)<=RocketTime(3)
                            thrust_type1_p6(i)=phase_data(2,6)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^3- (RocketTime (2) - RocketTime (3))^3)) * ((time_p6(i)-RocketTime (3))^3-(RocketTime (2) - RocketTime (3))^3)));
                        elseif time_p6(i)<=RocketTime(4)
                            thrust_type1_p6(i)=phase_data(2,6)*(RocketThrust (4) + ((RocketThrust (4) -RocketThrust (3)) * (time_p6(i)-RocketTime (4))/(RocketTime (4) -RocketTime (3))));
                        elseif time_p6(i)<=RocketTime(5)
                            thrust_type1_p6(i)=phase_data(2,6)*(RocketThrust (5) + ((RocketThrust (5)-RocketThrust (4)) * (time_p6(i)-RocketTime (5))/(RocketTime (5) -RocketTime (4))));
                        elseif time_p6(i)<=RocketTime(6)
                            thrust_type1_p6(i)=phase_data(2,6)*( RocketThrust (6) + ((RocketThrust (6)-RocketThrust (5)) * (time_p6(i)-RocketTime (6))/(RocketTime (6) -RocketTime (5))));
                        elseif time_p6(i)<=RocketTime(7)
                            thrust_type1_p6(i)=phase_data(2,6)*( RocketThrust (6) + (((RocketThrust (7) -RocketThrust (6))/((RocketTime (7) -RocketTime (7))^3-(RocketTime (6) - RocketTime (7))^3)) * ((time_p6(i)-RocketTime (7))^3- (RocketTime (6) - RocketTime (7))^3)));
                        else
                            thrust_type1_p6(i)=0;
                        end
                    end

                    %type2
                    if time_p6(i)>=rocket_data(2,4)
                        mp_type2_p6(i)=rocket_data(2,2)*phase_data(3,6);
                    else
                        mp_type2_p6(i)=(rocket_data(2,2)*phase_data(3,6)/rocket_data(2,4))*time_p6(i);
                    end

                    if (mp_type2_p6(i)==0 || (mp_type2_p6(i)-mp_type2_p6(i-1))==0)
                        thrust_type2_p6(i)=0;
                    else
                        thrust_type2_p6(i)=rocket_data(2,5)*phase_data(3,6);
                    end

                    %type3
                    if time_p6(i)>=rocket_data(3,4)
                        mp_type3_p6(i)=rocket_data(3,2)*phase_data(4,6);
                    else
                        mp_type3_p6(i)=(rocket_data(3,2)*phase_data(4,6)/rocket_data(3,4))*time_p6(i);
                    end
                    if (mp_type3_p6(i)==0 || (mp_type3_p6(i)-mp_type3_p6(i-1))==0)
                        thrust_type3_p6(i)=0;
                    else
                        thrust_type3_p6(i)=rocket_data(3,5)*phase_data(4,6);
                    end

                    %type4
                    if time_p6(i)>=rocket_data(4,4)
                        mp_type4_p6(i)=rocket_data(4,2)*phase_data(5,6);
                    else
                        mp_type4_p6(i)=(rocket_data(4,2)*phase_data(5,6)/rocket_data(4,4))*time_p6(i);
                    end
                    if (mp_type4_p6(i)==0 || (mp_type4_p6(i)-mp_type4_p6(i-1))==0)
                        thrust_type4_p6(i)=0;
                    else
                        thrust_type4_p6(i)=rocket_data(4,5)*phase_data(5,6);
                    end

                    %type5
                    if time_p6(i)>=rocket_data(5,4)
                        mp_type5_p6(i)=rocket_data(5,2)*phase_data(6,6);
                    else
                        mp_type5_p6(i)=(rocket_data(5,2)*phase_data(6,6)/rocket_data(5,4))*time_p6(i);
                    end

                    if (mp_type5_p6(i)==0 || (mp_type5_p6(i)-mp_type5_p6(i-1))==0)
                        thrust_type5_p6(i)=0;
                    else
                        RocketTime=ProfileRocketTime.rrm1((app.type*7+1):(app.type*7+7));
                        RocketThrust=ProfileRocketThrust.rrm1((app.type*7+1):(app.type*7+7));
                        if time_p6(i)<=RocketTime(2)
                            thrust_type5_p6(i)=phase_data(6,6)*(RocketThrust(1)+(((RocketThrust(2)-RocketThrust(1))/(RocketTime(2)^3-RocketTime(1)^3))*(time_p6(i)^3-RocketTime(1)^3)));
                        elseif time_p6(i)<=RocketTime (3)
                            thrust_type5_p6(i)=phase_data(6,6)*(RocketThrust (2) + (((RocketThrust (3)-RocketThrust (2))/((RocketTime (3) -RocketTime (3))^2- (RocketTime (2) - RocketTime (3))^2)) * ((time_p6(i)-RocketTime (3))^2-(RocketTime (2) - RocketTime (3))^2)));
                        elseif time_p6(i)<=RocketTime (4)
                            thrust_type5_p6(i)=phase_data(6,6)*(RocketThrust (3) + (((RocketThrust (4) -RocketThrust (3))/(RocketThrust(4)^3 -RocketThrust(3)^3))*(time_p6(i)^3-RocketTime(3)^3)));
                        elseif time_p6(i)<=RocketTime (5)
                            thrust_type5_p6(i)=phase_data(6,6)*(RocketThrust (4) + (((RocketThrust (5) -RocketThrust (4))/(RocketThrust(5)^3 -RocketThrust(4)^3))*(time_p6(i)^3-RocketTime(4)^3)));
                        elseif time_p6(i)<=RocketTime (6)
                            thrust_type5_p6(i)=phase_data(6,6)*(RocketThrust (5) + (((RocketThrust (6) -RocketThrust (5))/(RocketThrust(6)^3 -RocketThrust(5)^3))*(time_p6(i)^3-RocketTime(5)^3)));
                        elseif time_p6(i)<=RocketTime (7)
                            thrust_type5_p6(i)=phase_data(6,6)*(RocketThrust (6) + (((RocketThrust (7)-RocketThrust (6))/((RocketTime (7) -RocketTime (7))^2- (RocketTime (6) - RocketTime (7))^2)) * ((time_p6(i)-RocketTime (7))^2-(RocketTime (6) - RocketTime (7))^2)));
                        else
                            thrust_type5_p6(i)=0;
                        end
                    end
                end

                mass_of_propellent_used(i-1)=((mp_type1_p1(i-1)+mp_type2_p1(i-1)+mp_type3_p1(i-1)+mp_type4_p1(i-1)+mp_type5_p1(i-1))+ ...
                    (mp_type1_p2(i-1)+mp_type2_p2(i-1)+mp_type3_p2(i-1)+mp_type4_p2(i-1)+mp_type5_p2(i-1))+ ...
                    (mp_type1_p3(i-1)+mp_type2_p3(i-1)+mp_type3_p3(i-1)+mp_type4_p3(i-1)+mp_type5_p3(i-1))+ ...
                    (mp_type1_p4(i-1)+mp_type2_p4(i-1)+mp_type3_p4(i-1)+mp_type4_p4(i-1)+mp_type5_p4(i-1))+ ...
                    (mp_type1_p5(i-1)+mp_type2_p5(i-1)+mp_type3_p5(i-1)+mp_type4_p5(i-1)+mp_type5_p5(i-1))+ ...
                    (mp_type1_p6(i-1)+mp_type2_p6(i-1)+mp_type3_p6(i-1)+mp_type4_p6(i-1)+mp_type5_p6(i-1)));
                current_mass(i)=total_initial_mass-mass_of_propellent_used(i-1);

                thrust_current(i)=(thrust_type1_p1(i-1)+thrust_type2_p1(i-1)+thrust_type3_p1(i-1)+thrust_type4_p1(i-1)+thrust_type5_p1(i-1))+...
                    (thrust_type1_p2(i-1)+thrust_type2_p2(i-1)+thrust_type3_p2(i-1)+thrust_type4_p2(i-1)+thrust_type5_p2(i-1))+...
                    (thrust_type1_p3(i-1)+thrust_type2_p3(i-1)+thrust_type3_p3(i-1)+thrust_type4_p3(i-1)+thrust_type5_p3(i-1))-...
                    (thrust_type1_p4(i-1)+thrust_type2_p4(i-1)+thrust_type3_p4(i-1)+thrust_type4_p4(i-1)+thrust_type5_p4(i-1))-...
                    (thrust_type1_p5(i-1)+thrust_type2_p5(i-1)+thrust_type3_p5(i-1)+thrust_type4_p5(i-1)+thrust_type5_p5(i-1))-...
                    (thrust_type1_p6(i-1)+thrust_type2_p6(i-1)+thrust_type3_p6(i-1)+thrust_type4_p6(i-1)+thrust_type5_p6(i-1));

                %friction_drag
                if (velocity(i-1)>=0 && velocity(i-1)<=limiting_velocity)
                    coefficient_of_friction(i-1)=coefficient_of_friction_s+(((coefficient_of_friction_k-coefficient_of_friction_s)/limiting_velocity)*(velocity(i-1)-initial_velocity));
                elseif (velocity(i-1)>limiting_velocity)
                    coefficient_of_friction(i-1)=coefficient_of_friction_k;
                else
                    coefficient_of_friction(i-1)=coefficient_of_friction_s;
                end
                friction_drag(i-1)=(coefficient_of_friction(i-1)*current_mass(i-1)*G);

                %air_drag_calculation
                air_drag(i)=0.5*1*density*frontal_area*velocity(i)*velocity(i);

                %net_force_calculation
                drag(i)=(air_drag(i)+friction_drag(i-1));
                net_force(i)=thrust_current(i)-drag(i);

                %accleration_calculation
                acc(i)=net_force(i)./current_mass(i);
                vel_mach(i)=velocity(i)/343;

            end
            vel_max=max(velocity);

            for j=1:1000
                if (velocity(j)==vel_max)
                    distance_at_maxvel=current_pos(j);
                    break
                end
            end

            global length_of_rope

            length_of_rope=(distance_at_maxvel+25);

            global runtime
            for k=1:100000
                if (current_pos(k)>=length_of_rope)
                    runtime=current_time(k);
                    break;
                end
            end

            acc_max=max(acc);
            
            global acc_min
            for k=1:10000
                if(current_pos(k)>=length_of_rope)
                    acc_min=acc(k);
                    break
                end
            end

            max_load=max(net_force);

            %acc_at_maxload
            for m=1:10000
                if (net_force(m)==max_load)
                    acc_at_maxload=acc(m);
                    break
                end
            end

            %mass_at_maxload
            for m=1:10000
                if (net_force(m)==max_load)
                    mass_at_maxload=current_mass(m);
                    break
                end
            end

            x1=app.CentreofgravityxmEditField.Value;
            if (isempty(x1))
                warndlg("Please enter Centre of gravity,x.");
            end

            y1=app.CentreofgravityymEditField.Value;
            if (isempty(y1))
                warndlg("Please enter Centre of gravity,y.");
            end
            
            y2=app.DistancebetweenlineofcenterofgravityandthrustlinemEditField.Value;
            if (isempty(y2))
                warndlg("Please enter Distance between line of center of gravity and thrust line.");
            end

            l=app.DistancebetweenclampsmEditField.Value;
            if (isempty(l))
                warndlg("Please enter Distance between clamps.");
            end
            % R2=(((mass_at_maxload*G*x1)+(mass_at_maxload*acc_at_maxload*y1)-(max_load*y2))/l)/2;
            % R1=(((mass_at_maxload*G*x1)+(mass_at_maxload*acc_at_maxload*y1)-(max_load*y2))/l)/2;
            % R3=((mass_at_maxload*G)-((((mass_at_maxload*G*x1)+(mass_at_maxload*acc_at_maxload*y1)-(max_load*y2))/l)))/2;
            % R4=((mass_at_maxload*G)-((((mass_at_maxload*G*x1)+(mass_at_maxload*acc_at_maxload*y1)-(max_load*y2))/l)))/2;

            global vel_at_maxload
            %vel_at_maxload
            for l=1:10000
                if (net_force(l)==max_load)
                    vel_at_maxload=velocity(l);
                    break
                end
            end

            global dist_at_maxload
            %dist_at_maxload
            for m=1:10000
                if (net_force(m)==max_load)
                    dist_at_maxload=current_pos(m);
                    break
                end
            end

            
            global dia_arr
            global massperunitlen_arr
            global strength_arr

            global diameter
            global strength
            global Tension_0
            global Tension_1
            global massperunitlength
            
            for u=1:length(dia_arr)
                if (strength_arr(u)*1000)>=max_load
                    strength=strength_arr(u);
                    diameter=dia_arr(u);
                    massperunitlength=massperunitlen_arr(u);
                    break;
                end
            end

            l=length_of_rope;

            H=app.VerticalGapbetweenSupportsmEditField.Value;

            if (isempty(H))
                warndlg("Please enter Vertical gap betwwen the two supports.");
            end
            w=massperunitlength;
            format longG;
            Tension_0=((w*(l^2-H^2))/(2*H))*G;
            Tension_1=((w*(l^2+H^2))/(2*H))*G;

            app.DialogApp= output(app,distance_at_maxvel,vel_max,acc_max,acc_min,runtime,vel_at_maxload,dist_at_maxload,max_load);            
        end

        % Button pushed function: GenerateGraphButton
        function GenerateGraphButtonPushed(app, event)
            global current_pos
            global current_time
            global velocity
            global acc
            global current_mass
            global thrust_current
            global trackendcount

            global trackendcount
            for m=1:length(current_pos)
                if (current_pos(m)>=4000)
                    trackendcount=m-1;
                    break
                end
            end

            figure(1);
            ax1=gca;
            plot(ax1,current_time(1:trackendcount),current_pos(1:trackendcount));
            ylabel(ax1,'Distance(m)');
            xlabel(ax1,'Time(s)');
            title("Distance VS Time");
            grid on;

            figure(2);
            ax2=gca;
            plot(ax2,current_time(1:trackendcount),current_mass(1:trackendcount));
            ylabel(ax2,'Mass(kg)');
            xlabel(ax2,'Time(s)');
            title("Mass VS Time");
            grid on;

            figure(3);
            ax3=gca;
            plot(ax3,current_time(1:trackendcount),velocity(1:trackendcount));
            ylabel(ax3,'Velocity(m/s)');
            xlabel(ax3,'Time(s)');
            title("Velocity VS Time");
            grid on;

            figure(4);
            ax4=gca;
            plot(ax4,current_time(2:trackendcount),thrust_current(2:trackendcount));
            ylabel(ax4,'Thrust(N)');
            xlabel(ax4,'Time(s)');
            title("Thrust VS Time");
            grid on;

            figure(5);
            ax5=gca;
            plot(ax5,current_time(2:trackendcount),acc(2:trackendcount));
            ylabel(ax5,'Accleration');
            xlabel(ax5,'Time');
            title("Accleration VS Time");
            grid on;

            figure(6);
            ax6=gca;
            plot(ax6,current_pos(2:trackendcount),velocity(2:trackendcount));
            ylabel(ax6,'Velocity(m/s)');
            xlabel(ax6,'Distance(m)');
            title("Velocity VS Distance");
            grid on;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PayloadandClampSpecificationsPanel = uipanel(app.UIFigure);
            app.PayloadandClampSpecificationsPanel.Title = 'Payload and Clamp Specifications';
            app.MassofPayloadkgEditFieldLabel = uilabel(app.PayloadandClampSpecificationsPanel);
            app.MassofPayloadkgEditFieldLabel.Text = 'Mass of Payload(kg)';
            app.MassofPayloadkgEditField = uieditfield(app.PayloadandClampSpecificationsPanel, 'numeric');
            app.MassofPayloadkgEditField.LowerLimitInclusive = 'off';
            app.MassofPayloadkgEditField.Limits = [0 Inf];
            app.MassofPayloadkgEditField.AllowEmpty = 'on';
            app.MassofPayloadkgEditField.Placeholder = 'Enter';
            app.MassofPayloadkgEditField.Value = [];
            app.MassofclampskgEditFieldLabel = uilabel(app.PayloadandClampSpecificationsPanel);
            app.MassofclampskgEditFieldLabel.Text = 'Mass of clamps(kg)';
            app.MassofclampskgEditField = uieditfield(app.PayloadandClampSpecificationsPanel, 'numeric');
            app.MassofclampskgEditField.LowerLimitInclusive = 'off';
            app.MassofclampskgEditField.Limits = [0 Inf];
            app.MassofclampskgEditField.AllowEmpty = 'on';
            app.MassofclampskgEditField.Placeholder = 'Enter';
            app.MassofclampskgEditField.Value = [];
            app.CentreofgravityxmLabel = uilabel(app.PayloadandClampSpecificationsPanel);
            app.CentreofgravityxmLabel.Text = 'Centre of gravity x(m)';
            app.CentreofgravityxmEditField = uieditfield(app.PayloadandClampSpecificationsPanel, 'numeric');
            app.CentreofgravityxmEditField.AllowEmpty = 'on';
            app.CentreofgravityxmEditField.Placeholder = 'Enter';
            app.CentreofgravityxmEditField.Value = [];
            app.CentreofgravityymLabel = uilabel(app.PayloadandClampSpecificationsPanel);
            app.CentreofgravityymLabel.Text = 'Centre of gravity y(m)';
            app.CentreofgravityymEditField = uieditfield(app.PayloadandClampSpecificationsPanel, 'numeric');
            app.CentreofgravityymEditField.AllowEmpty = 'on';
            app.CentreofgravityymEditField.Placeholder = 'Enter';
            app.CentreofgravityymEditField.Value = [];
            app.GapLabel = uilabel(app.PayloadandClampSpecificationsPanel);
            app.GapLabel.Text = 'Distance between  line of center of gravity and thrust line(m)';
            app.DistancebetweenlineofcenterofgravityandthrustlinemEditField = uieditfield(app.PayloadandClampSpecificationsPanel, 'numeric');
            app.DistancebetweenlineofcenterofgravityandthrustlinemEditField.AllowEmpty = 'on';
            app.DistancebetweenlineofcenterofgravityandthrustlinemEditField.Placeholder = 'Enter';
            app.DistancebetweenlineofcenterofgravityandthrustlinemEditField.Value = [];
            app.DistancebetweenclampsmEditFieldLabel = uilabel(app.PayloadandClampSpecificationsPanel);
            app.DistancebetweenclampsmEditFieldLabel.Text = 'Distance between clamps(m)';
            app.DistancebetweenclampsmEditField = uieditfield(app.PayloadandClampSpecificationsPanel, 'numeric');
            app.DistancebetweenclampsmEditField.Limits = [0 Inf];
            app.DistancebetweenclampsmEditField.AllowEmpty = 'on';
            app.DistancebetweenclampsmEditField.Placeholder = 'Enter';
            app.DistancebetweenclampsmEditField.Value = [];
            app.AirDragPanel = uipanel(app.UIFigure);
            app.AirDragPanel.Title = 'Air Drag';
            app.DensityofairkgcubicmEditField = uieditfield(app.AirDragPanel, 'numeric');
            app.DensityofairkgcubicmEditField.Limits = [0 Inf];
            app.DensityofairkgcubicmEditField.AllowEmpty = 'on';
            app.DensityofairkgcubicmEditField.Editable = 'off';
            app.DensityofairkgcubicmEditField.Placeholder = 'Enter';
            app.DensityofairkgcubicmEditField.Value = 1.226;
            app.DensityofairkgcubicmEditFieldLabel = uilabel(app.AirDragPanel);
            app.DensityofairkgcubicmEditFieldLabel.Text = 'Density of air(kg/cubic m)';
            app.ShapeProfileButtonGroup = uibuttongroup(app.AirDragPanel);
            app.ShapeProfileButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShapeProfileButtonGroupSelectionChanged, true);
            app.ShapeProfileButtonGroup.Title = 'Shape/Profile';
            app.ConicalButton = uiradiobutton(app.ShapeProfileButtonGroup);
            app.ConicalButton.Text = 'Conical';
            app.ConicalButton.Value = true;
            app.OgiveButton = uiradiobutton(app.ShapeProfileButtonGroup);
            app.OgiveButton.Text = 'Ogive';
            app.UnitButton = uiradiobutton(app.ShapeProfileButtonGroup);
            app.UnitButton.Text = 'Unit';
            app.TotalDragAreasqmEditFieldLabel = uilabel(app.AirDragPanel);
            app.TotalDragAreasqmEditFieldLabel.Text = 'Total Drag Area(sq m)';
            app.TotalDragAreasqmEditField = uieditfield(app.AirDragPanel, 'numeric');
            app.TotalDragAreasqmEditField.LowerLimitInclusive = 'off';
            app.TotalDragAreasqmEditField.Limits = [0 Inf];
            app.TotalDragAreasqmEditField.AllowEmpty = 'on';
            app.TotalDragAreasqmEditField.Placeholder = 'Enter';
            app.TotalDragAreasqmEditField.Value = [];
            app.GenerateGraphButton = uibutton(app.UIFigure, 'push');
            app.GenerateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButtonPushed, true);
            app.GenerateGraphButton.Text = 'Generate Graph';
            app.RopewayEstimationLabel = uilabel(app.UIFigure);
            app.RopewayEstimationLabel.Text = 'Ropeway Estimation';
            app.PhaseFiringPlanDataPanel = uipanel(app.UIFigure);
            app.PhaseFiringPlanDataPanel.Title = 'Phase Firing Plan Data';
            app.UITable = uitable(app.PhaseFiringPlanDataPanel);
            app.UITable.ColumnName = {'F_Phase1'; 'F_Phase2'; 'F_Phase3'; 'R_Phase1'; 'R_Phase2'; 'R_Phase3'};
            app.UITable.RowName = {'Distance'; 'Type-1'; 'Type-2'; 'Type-3'; 'Type-4'; 'Type-5'};
            app.UITable.ColumnEditable = true;
            app.UpdateDataButton = uibutton(app.PhaseFiringPlanDataPanel, 'push');
            app.UpdateDataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataButtonPushed, true);
            app.UpdateDataButton.Text = 'Update Data';
            app.RocketPropertiesPanel = uipanel(app.UIFigure);
            app.RocketPropertiesPanel.Title = 'Rocket Properties';
            app.UITable_2 = uitable(app.RocketPropertiesPanel);
            app.UITable_2.ColumnName = {'Mass'; 'Propellent mass'; 'Dummy mass'; 'Burning time'; 'Thrust'; 'Burning Rate'};
            app.UITable_2.RowName = {'Type-1 '; 'Type-2 '; 'Type-3 '; 'Type-4 '; 'Type-5'};
            app.UITable_2.ColumnEditable = true;
            app.ComputeResultButton = uibutton(app.UIFigure, 'push');
            app.ComputeResultButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeResultButtonPushed, true);
            app.ComputeResultButton.Text = 'Compute Result';
            app.InitialParametersPanel = uipanel(app.UIFigure);
            app.InitialParametersPanel.Title = 'Initial Parameters';
            app.InitialVelocityEditFieldLabel = uilabel(app.InitialParametersPanel);
            app.InitialVelocityEditFieldLabel.Text = 'Initial Velocity';
            app.InitialVelocityEditField = uieditfield(app.InitialParametersPanel, 'numeric');
            app.InitialVelocityEditField.Limits = [0 Inf];
            app.InitialVelocityEditField.AllowEmpty = 'on';
            app.InitialVelocityEditField.Placeholder = 'Enter';
            app.InitialVelocityEditField.Value = [];
            app.TimeincrementEditFieldLabel = uilabel(app.InitialParametersPanel);
            app.TimeincrementEditFieldLabel.Text = 'Time increment';
            app.TimeincrementEditField = uieditfield(app.InitialParametersPanel, 'numeric');
            app.TimeincrementEditField.LowerLimitInclusive = 'off';
            app.TimeincrementEditField.Limits = [0 Inf];
            app.TimeincrementEditField.AllowEmpty = 'on';
            app.TimeincrementEditField.Placeholder = 'Enter';
            app.TimeincrementEditField.Value = [];
            app.TemperatureConditionsButtonGroup = uibuttongroup(app.InitialParametersPanel);
            app.TemperatureConditionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TemperatureConditionsButtonGroupSelectionChanged, true);
            app.TemperatureConditionsButtonGroup.Title = 'Temperature Conditions';
            app.ColdButton = uiradiobutton(app.TemperatureConditionsButtonGroup);
            app.ColdButton.Text = 'Cold';
            app.ColdButton.Value = true;
            app.AmbientButton = uiradiobutton(app.TemperatureConditionsButtonGroup);
            app.AmbientButton.Text = 'Ambient';
            app.HotButton = uiradiobutton(app.TemperatureConditionsButtonGroup);
            app.HotButton.Text = 'Hot';
            app.RopeSpecificationsPanel = uipanel(app.UIFigure);
            app.RopeSpecificationsPanel.Title = 'Rope Specifications';
            app.TypeofRopeDropDownLabel = uilabel(app.RopeSpecificationsPanel);
            app.TypeofRopeDropDownLabel.Text = 'Type of Rope';
            app.TypeofRopeDropDown = uidropdown(app.RopeSpecificationsPanel);
            app.TypeofRopeDropDown.Items = {'Locked Coil Ropes', '6 Strand Steel Core', '6X19 Fibre Core'};
            app.TypeofRopeDropDown.ClickedFcn = createCallbackFcn(app, @TypeofRopeDropDownClicked, true);
            app.TypeofRopeDropDown.Value = 'Locked Coil Ropes';
            app.VerticalGapbetweenSupportsmEditFieldLabel = uilabel(app.RopeSpecificationsPanel);
            app.VerticalGapbetweenSupportsmEditFieldLabel.Text = 'Vertical Gap between Supports(m)';
            app.VerticalGapbetweenSupportsmEditField = uieditfield(app.RopeSpecificationsPanel, 'numeric');
            app.VerticalGapbetweenSupportsmEditField.Limits = [0 Inf];
            app.VerticalGapbetweenSupportsmEditField.AllowEmpty = 'on';
            app.VerticalGapbetweenSupportsmEditField.Placeholder = 'Enter';
            app.VerticalGapbetweenSupportsmEditField.Value = [];
            app.FrictionPanel_2 = uipanel(app.UIFigure);
            app.FrictionPanel_2.Title = 'Friction';
            app.LimitingVelocitymsecEditField_2Label = uilabel(app.FrictionPanel_2);
            app.LimitingVelocitymsecEditField_2Label.Text = 'Limiting Velocity(m/sec)';
            app.LimitingVelocitymsecEditField_2 = uieditfield(app.FrictionPanel_2, 'numeric');
            app.LimitingVelocitymsecEditField_2.LowerLimitInclusive = 'off';
            app.LimitingVelocitymsecEditField_2.Limits = [0 Inf];
            app.LimitingVelocitymsecEditField_2.AllowEmpty = 'on';
            app.LimitingVelocitymsecEditField_2.Placeholder = 'Enter';
            app.LimitingVelocitymsecEditField_2.Value = [];
            app.Coefficient_of_frictionKnob = uiknob(app.FrictionPanel_2, 'continuous');
            app.Coefficient_of_frictionKnob.Limits = [0 1];
            app.Coefficient_of_frictionKnob.ValueChangedFcn = createCallbackFcn(app, @Coefficient_of_frictionKnobValueChanged, true);
            app.Coefficient_of_frictionKnob.ValueChangingFcn = createCallbackFcn(app, @Coefficient_of_frictionKnobValueChanging2, true);
            app.Coefficient_of_frictionKnob_2 = uiknob(app.FrictionPanel_2, 'continuous');
            app.Coefficient_of_frictionKnob_2.Limits = [0 1];
            app.Coefficient_of_frictionKnob_2.ValueChangedFcn = createCallbackFcn(app, @Coefficient_of_frictionKnob_2ValueChanged2, true);
            app.Coefficient_of_frictionKnob_2.ValueChangingFcn = createCallbackFcn(app, @Coefficient_of_frictionKnob_2ValueChanging2, true);
            app.StaticLabel = uilabel(app.FrictionPanel_2);
            app.StaticLabel.Text = 'Static';
            app.KineticLabel = uilabel(app.FrictionPanel_2);
            app.KineticLabel.Text = 'Kinetic';
            app.CfstaticEditField_2Label = uilabel(app.FrictionPanel_2);
            app.CfstaticEditField_2Label.Text = 'Cf static';
            app.CfstaticEditField_2 = uieditfield(app.FrictionPanel_2, 'numeric');
            app.CfstaticEditField_2.AllowEmpty = 'on';
            app.CfstaticEditField_2.Editable = 'off';
            app.CfstaticEditField_2.Placeholder = 'Use knob';
            app.CfstaticEditField_2.Value = [];
            app.CfkineticEditField_2Label = uilabel(app.FrictionPanel_2);
            app.CfkineticEditField_2Label.Text = 'Cf kinetic';
            app.CfkineticEditField_2 = uieditfield(app.FrictionPanel_2, 'numeric');
            app.CfkineticEditField_2.AllowEmpty = 'on';
            app.CfkineticEditField_2.Editable = 'off';
            app.CfkineticEditField_2.Placeholder = 'Use Knob';
            app.CfkineticEditField_2.Value = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = important_6phases_update18

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end