classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GRMSPanel                      matlab.ui.container.Panel
        GRMSZEditField                 matlab.ui.control.NumericEditField
        GRMSZEditFieldLabel            matlab.ui.control.Label
        GRMSYEditField                 matlab.ui.control.NumericEditField
        GRMSYEditFieldLabel            matlab.ui.control.Label
        GRMSXEditField                 matlab.ui.control.NumericEditField
        GRMSXEditFieldLabel            matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        PlotforVelocityPanel           matlab.ui.container.Panel
        PlotPSDvelocityButton          matlab.ui.control.Button
        PlotGlevelsvelocityButton      matlab.ui.control.Button
        PlotfortimePanel               matlab.ui.container.Panel
        PlotPSDtimeButton              matlab.ui.control.Button
        PlotGlevelstimeButton          matlab.ui.control.Button
        ExportGraphicsButton           matlab.ui.control.Button
        InputParametersPanel           matlab.ui.container.Panel
        VelocityPanel                  matlab.ui.container.Panel
        EnteruppervEditField           matlab.ui.control.NumericEditField
        EnteruppervEditFieldLabel      matlab.ui.control.Label
        EnterlowervEditField           matlab.ui.control.NumericEditField
        EnterlowervEditFieldLabel      matlab.ui.control.Label
        TimePanel                      matlab.ui.container.Panel
        EnterlowertEditField           matlab.ui.control.NumericEditField
        EnterlowertEditFieldLabel      matlab.ui.control.Label
        EnteruppertEditField           matlab.ui.control.NumericEditField
        EnteruppertEditFieldLabel      matlab.ui.control.Label
        UseifvelocitiesarefromaccelerationanddecelerationPanel  matlab.ui.container.Panel
        PlotPSDButton                  matlab.ui.control.Button
        PlotGlevelsButton              matlab.ui.control.Button
        InputSensitivitiesconvertintoVgforODARPanel  matlab.ui.container.Panel
        SensitivityofzEditField        matlab.ui.control.NumericEditField
        SensitivityofzEditFieldLabel   matlab.ui.control.Label
        SensitivityofyEditField        matlab.ui.control.NumericEditField
        SensitivityofyEditFieldLabel   matlab.ui.control.Label
        SensitivityofxEditField        matlab.ui.control.NumericEditField
        SensitivityofxEditFieldLabel   matlab.ui.control.Label
        ImportPanel                    matlab.ui.container.Panel
        filenameEditField_2            matlab.ui.control.EditField
        filenameEditField_2Label       matlab.ui.control.Label
        ImportvelocityfileButton       matlab.ui.control.Button
        filenameEditField              matlab.ui.control.EditField
        filenameEditFieldLabel         matlab.ui.control.Label
        ImportsignalfileButton         matlab.ui.control.Button
        EntervalueofSamplingrateEditField  matlab.ui.control.NumericEditField
        EntervalueofSamplingrateLabel  matlab.ui.control.Label
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % 
     properties (Access = private)

     end
  
    
    methods (Access = public)
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportsignalfileButton
        function ImportsignalfileButtonPushed(app, event)
            [filename,path]=uigetfile();
            app.filenameEditField.Value=filename;
        end

        % Value changed function: SensitivityofxEditField
        function SensitivityofxEditFieldValueChanged(app, event)
            value = app.SensitivityofxEditField.Value;
            
        end

        % Value changed function: EntervalueofSamplingrateEditField
        function EntervalueofSamplingrateEditFieldValueChanged(app, event)
            value = app.EntervalueofSamplingrateEditField.Value;
            
        end

        % Button pushed function: PlotGlevelstimeButton
        function PlotGlevelstimeButtonPushed(app, event)
             % Glevels calculation for Time domain
            T=readtable(app.filenameEditField.Value);
              indexl=(((app.EntervalueofSamplingrateEditField.Value)*(app.EnterlowertEditField.Value))+1);
              indexu=(((app.EntervalueofSamplingrateEditField.Value)*(app.EnteruppertEditField.Value))+1);
              timedata=T.(cell2mat(T.Properties.VariableNames(1)))((indexl):(indexu));
              voltagedatax=T.(cell2mat(T.Properties.VariableNames(2)))((indexl):(indexu));
              voltagedatay=T.(cell2mat(T.Properties.VariableNames(3)))((indexl):(indexu));
              voltagedataz=T.(cell2mat(T.Properties.VariableNames(4)))((indexl):(indexu));
                glevelx=(voltagedatax./app.SensitivityofxEditField.Value)*10^3;
                glevely=(voltagedatay./app.SensitivityofyEditField.Value)*10^3;
                glevelz=(voltagedataz./app.SensitivityofzEditField.Value)*10^3;
              plot(app.UIAxes,timedata,glevelx)
              plot(app.UIAxes2,timedata,glevely)
              plot(app.UIAxes3,timedata,glevelz)
   
        end

        % Value changed function: SensitivityofyEditField
        function SensitivityofyEditFieldValueChanged(app, event)
            value = app.SensitivityofyEditField.Value;
            
        end

        % Value changed function: SensitivityofzEditField
        function SensitivityofzEditFieldValueChanged(app, event)
            value = app.SensitivityofzEditField.Value;
            
        end

        % Value changed function: filenameEditField
        function filenameEditFieldValueChanged2(app, event)
            value = app.filenameEditField.Value;
            value = app.filenameEditField.Value;
            app.filenameEditField.Value=filename;
             figure(app.UIFigure)
            
            
        end

        % Button pushed function: PlotPSDtimeButton
        function PlotPSDtimeButtonPushed(app, event)
           % Calculation for PSD for Time domain
            T=readtable(app.filenameEditField.Value);
            indexl=(((app.EntervalueofSamplingrateEditField.Value)*(app.EnterlowertEditField.Value))+1);
            indexu=(((app.EntervalueofSamplingrateEditField.Value)*(app.EnteruppertEditField.Value))+1);
            voltagedatax=T.(cell2mat(T.Properties.VariableNames(2)))((indexl):(indexu));
            voltagedatay=T.(cell2mat(T.Properties.VariableNames(3)))((indexl):(indexu));
            voltagedataz=T.(cell2mat(T.Properties.VariableNames(4)))((indexl):(indexu));
                 glevelx=(voltagedatax./app.SensitivityofxEditField.Value)*10^3;
                glevely=(voltagedatay./app.SensitivityofyEditField.Value)*10^3;
                glevelz=(voltagedataz./app.SensitivityofzEditField.Value)*10^3;
            
            [pxx_xaxis,f_xaxis] = pwelch( glevelx,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes4,f_xaxis,pxx_xaxis)
            limx=(f_xaxis>0)&(f_xaxis<=2000);
            X=trapz(f_xaxis(limx),pxx_xaxis(limx));
            X=(X)^(1/2);
            app.GRMSXEditField.Value=X;
            [pxx_yaxis,f_yaxis] = pwelch( glevely,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes5,f_yaxis,pxx_yaxis)
            limy=(f_yaxis>0)&(f_yaxis<=2000);
            Y=trapz(f_yaxis(limy),pxx_yaxis(limy));
            Y=(Y)^(1/2);
            app.GRMSYEditField.Value=Y;
            [pxx_zaxis,f_zaxis] = pwelch( glevelz,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
           loglog(app.UIAxes6,f_zaxis,pxx_zaxis)
           limz=(f_zaxis>0)&(f_zaxis<=2000);
           Z=trapz(f_zaxis(limz),pxx_zaxis(limz));
            Z=(Z)^(1/2);
            app.GRMSZEditField.Value=Z;
        end

        % Button pushed function: ImportvelocityfileButton
        function ImportvelocityfileButtonPushed(app, event)
            [filename,path]=uigetfile();
            app.filenameEditField_2.Value=filename;
        end

        % Value changed function: filenameEditField_2
        function filenameEditField_2ValueChanged(app, event)
            value = app.filenameEditField_2.Value;
             app.filenameEditField_2.Value=filename;
             figure(app.UIFigure)
            
        end

        % Button pushed function: PlotGlevelsvelocityButton
        function PlotGlevelsvelocityButtonPushed(app, event)
           %  Calculation of Glevels when Velocity is given as input
           %  parameter
             A=readtable(app.filenameEditField_2.Value); %velocity file read
n=size(A.(cell2mat(A.Properties.VariableNames(1))));     %no. of rows in velocity file
  if (app.EnterlowervEditField.Value<=app.EnteruppervEditField.Value)
      % loop for finding the closest value of given lower velocity in velocity file  
i=1;
while(i<(n(1)+1)) % loop will run until velocity value becomes greater than given value
   if(A.(cell2mat(A.Properties.VariableNames(2)))(i)>=app.EnterlowervEditField.Value)
        upper_bound=A.(cell2mat(A.Properties.VariableNames(2)))(i);
        lower_bound=A.(cell2mat(A.Properties.VariableNames(2)))(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.EnterlowervEditField.Value-upper_bound)>abs(app.EnterlowervEditField.Value-lower_bound))
app.EnterlowervEditField.Value=lower_bound; 
else
    app.EnterlowervEditField.Value=upper_bound; 

end
% loop for finding the closest value of given upper velocity in velocity file
 j=1;
      
while(j<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(2)))(j)>=app.EnteruppervEditField.Value)
        upper_bound2=A.(cell2mat(A.Properties.VariableNames(2)))(j);
        lower_bound2=A.(cell2mat(A.Properties.VariableNames(2)))(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.EnteruppervEditField.Value-upper_bound2)>abs(app.EnteruppervEditField.Value-lower_bound2))
app.EnteruppervEditField.Value=lower_bound2;
else
    app.EnteruppervEditField.Value=upper_bound2;

end

               tolerance=1e-11;
               rows=ismembertol(A.(cell2mat(A.Properties.VariableNames(2))),app.EnterlowervEditField.Value,tolerance);
               ilv=find(rows==1);
               rows1=ismembertol(A.(cell2mat(A.Properties.VariableNames(2))),app.EnteruppervEditField.Value,tolerance);
              
               iuv=find(rows1==1);
          
                 y1=A.(cell2mat(A.Properties.VariableNames(1)))(ilv);
                 y2=A.(cell2mat(A.Properties.VariableNames(1)))(iuv);
               T=readtable(app.filenameEditField.Value);
              tolerance=1e-10;
               v=ismembertol(T.(cell2mat(T.Properties.VariableNames(1))),y1,tolerance);
             
                ilvt=find(v==1);
                u=ismembertol(T.(cell2mat(T.Properties.VariableNames(1))),y2,tolerance);
                 
                 iuvt=find(u==1);
               timedatav=T.(cell2mat(T.Properties.VariableNames(1)))((ilvt):(iuvt));
               voltagedatavx=T.(cell2mat(T.Properties.VariableNames(2)))((ilvt):(iuvt));
               voltagedatavy=T.(cell2mat(T.Properties.VariableNames(3)))((ilvt):(iuvt));
               voltagedatavz=T.(cell2mat(T.Properties.VariableNames(4)))((ilvt):(iuvt));
                 glevelx=voltagedatavx./app.SensitivityofxEditField.Value;
                 glevely=voltagedatavy./app.SensitivityofyEditField.Value;
                glevelz=voltagedatavz./app.SensitivityofzEditField.Value;
                 velocitydata=A.(cell2mat(A.Properties.VariableNames(2)))((ilv):(iuv));
                timedata=A.(cell2mat(A.Properties.VariableNames(1)))((ilv):(iuv));
                yyaxis (app.UIAxes,'left')
                plot(app.UIAxes,timedatav,glevelx,"Color",[0 0.447 0.7410]);
                yyaxis (app.UIAxes,'right')
               plot(app.UIAxes,timedata,velocitydata)
               yyaxis (app.UIAxes2,'left')
                plot(app.UIAxes2,timedatav,glevely,"Color",[0 0.447 0.7410]);
                yyaxis (app.UIAxes2,'right')
              plot(app.UIAxes2,timedata,velocitydata)
             yyaxis (app.UIAxes3,'left')
                plot(app.UIAxes3,timedatav,glevelz,"Color",[0 0.447 0.7410]);
                yyaxis (app.UIAxes3,'right')
              plot(app.UIAxes3,timedata,velocitydata)
           
              
  else 
            m=max(A.(cell2mat(A.Properties.VariableNames(2))));
      tolerance=1e-15;

 ma=ismembertol(A.(cell2mat(A.Properties.VariableNames(2))),m,tolerance);
xm=find(ma==1);
maxdata=A.(cell2mat(A.Properties.VariableNames(2)))((xm):(end));
      i=1;
while(i<(n(1)+1))
   if(maxdata(i)<=app.EnterlowervEditField.Value)
        upper_bound=maxdata(i);
        lower_bound=maxdata(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.EnterlowervEditField.Value-upper_bound)>abs(app.EnterlowervEditField.Value-lower_bound))
app.EnterlowervEditField.Value=lower_bound;
else
    app.EnterlowervEditField.Value=upper_bound;

end
% for upperv
 j=1;


while(j<(n(1)+1))
   if(maxdata(j)<=app.EnteruppervEditField.Value)
        upper_bound2=maxdata(j);
        lower_bound2=maxdata(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.EnteruppervEditField.Value-upper_bound2)>abs(app.EnteruppervEditField.Value-lower_bound2))
app.EnteruppervEditField.Value=lower_bound2;
else
    app.EnteruppervEditField.Value=upper_bound2;

end
                  T=readtable(app.filenameEditField.Value);
     
idxlv=ismembertol(maxdata,app.EnterlowervEditField.Value,tolerance);
idxlvf=find(idxlv==1);
idxuv=ismembertol(maxdata,app.EnteruppervEditField.Value,tolerance);
idxuvf=find(idxuv==1);
idxlvf=idxlvf+xm-1;
idxuvf=idxuvf+xm-1;
idxlt=A.(cell2mat(A.Properties.VariableNames(1)))(idxlvf);
idxut=A.(cell2mat(A.Properties.VariableNames(1)))(idxuvf);
tolerance1=1e-6;
 tl=ismembertol(T.(cell2mat(T.Properties.VariableNames(1))),idxlt,tolerance1);
 xlf=find(tl==1);
  tu=ismembertol(T.(cell2mat(T.Properties.VariableNames(1))),idxut,tolerance1);
 xuf=find(tu==1);
 timedata2=T.(cell2mat(T.Properties.VariableNames(1)))((xlf):(xuf));
            voltagedatavx2=T.(cell2mat(T.Properties.VariableNames(2)))((xlf):(xuf));
               voltagedatavy2=T.(cell2mat(T.Properties.VariableNames(3)))((xlf):(xuf));
               voltagedatavz2=T.(cell2mat(T.Properties.VariableNames(4)))((xlf):(xuf));
                  glevelx2=voltagedatavx2./app.SensitivityofxEditField.Value;
                  glevely2=voltagedatavy2./app.SensitivityofyEditField.Value;
                 glevelz2=voltagedatavz2./app.SensitivityofzEditField.Value;
               % plot(app.UIAxes,timedata2,voltagedatavx2)
               % plot(app.UIAxes2,timedata2,voltagedatavy2)
               % plot(app.UIAxes3,timedata2,voltagedatavz2)
                velocitydata=A.(cell2mat(A.Properties.VariableNames(2)))((idxlvf):(idxuvf));
                timedata=A.(cell2mat(A.Properties.VariableNames(1)))((idxlvf):(idxuvf));
                yyaxis (app.UIAxes,'left')
                plot(app.UIAxes,timedata2,glevelx2,"Color",[0 0.447 0.7410]);
                yyaxis (app.UIAxes,'right')
               plot(app.UIAxes,timedata,velocitydata)
               yyaxis (app.UIAxes2,'left')
                plot(app.UIAxes2,timedata2,glevely2,"Color",[0 0.447 0.7410]);
                yyaxis (app.UIAxes2,'right')
              plot(app.UIAxes2,timedata,velocitydata)
             yyaxis (app.UIAxes3,'left')
                plot(app.UIAxes3,timedata2,glevelz2,"Color",[0 0.447 0.7410]);
                yyaxis (app.UIAxes3,'right')
              plot(app.UIAxes3,timedata,velocitydata)
   end
        end

        % Button pushed function: PlotPSDvelocityButton
        function PlotPSDvelocityButtonPushed(app, event)
                          A=readtable(app.filenameEditField_2.Value);
n=size(A.(cell2mat(A.Properties.VariableNames(1))));
  if (app.EnterlowervEditField.Value<=app.EnteruppervEditField.Value)
i=1;
while(i<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(2)))(i)>=app.EnterlowervEditField.Value)
        upper_bound=A.(cell2mat(A.Properties.VariableNames(2)))(i);
        lower_bound=A.(cell2mat(A.Properties.VariableNames(2)))(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.EnterlowervEditField.Value-upper_bound)>abs(app.EnterlowervEditField.Value-lower_bound))
app.EnterlowervEditField.Value=lower_bound;
else
    app.EnterlowervEditField.Value=upper_bound;

end
% for upperv
 j=1;
      
while(j<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(2)))(j)>=app.EnteruppervEditField.Value)
        upper_bound2=A.(cell2mat(A.Properties.VariableNames(2)))(j);
        lower_bound2=A.(cell2mat(A.Properties.VariableNames(2)))(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.EnteruppervEditField.Value-upper_bound2)>abs(app.EnteruppervEditField.Value-lower_bound2))
app.EnteruppervEditField.Value=lower_bound2;
else
   app.EnteruppervEditField.Value=upper_bound2;

end

               % rows=A.v==app.Currentlowerv;
               % if (app.Currentlowerv<app.Currentupperv)
               tolerance=1e-11;
               rows=ismembertol(A.(cell2mat(A.Properties.VariableNames(2))),app.EnterlowervEditField.Value,tolerance);
               ilv=find(rows==1);
               rows1=ismembertol(A.(cell2mat(A.Properties.VariableNames(2))),app.EnteruppervEditField.Value,tolerance);
              
               iuv=find(rows1==1);
          % new
                 y1=A.(cell2mat(A.Properties.VariableNames(1)))(ilv);
                 y2=A.(cell2mat(A.Properties.VariableNames(1)))(iuv);
               T=readtable(app.filenameEditField.Value);
              tolerance=1e-10;
               v=ismembertol(T.(cell2mat(T.Properties.VariableNames(1))),y1,tolerance);
             
                ilvt=find(v==1);
                u=ismembertol(T.(cell2mat(T.Properties.VariableNames(1))),y2,tolerance);
                 
                 iuvt=find(u==1);
               timedatav=T.(cell2mat(T.Properties.VariableNames(1)))((ilvt):(iuvt));
               voltagedatavx=T.(cell2mat(T.Properties.VariableNames(2)))((ilvt):(iuvt));
               voltagedatavy=T.(cell2mat(T.Properties.VariableNames(3)))((ilvt):(iuvt));
               voltagedatavz=T.(cell2mat(T.Properties.VariableNames(4)))((ilvt):(iuvt));
                
                glevelx=voltagedatavx./app.SensitivityofxEditField.Value;
                 glevely=voltagedatavy./app.SensitivityofyEditField.Value;
               glevelz=voltagedatavz./app.SensitivityofzEditField.Value;
                  [pxx_xaxis,f_xaxis] = pwelch( glevelx,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes4,f_xaxis,pxx_xaxis)
            limx=(f_xaxis>0)&(f_xaxis<=2000);
            X=trapz(f_xaxis(limx),pxx_xaxis(limx));
            X=(X)^(1/2);
            app.GRMSXEditField.Value=X;
            [pxx_yaxis,f_yaxis] = pwelch( glevely,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes5,f_yaxis,pxx_yaxis)
            limy=(f_yaxis>0)&(f_yaxis<=2000);
             Y=trapz(f_yaxis(limy),pxx_yaxis(limy));
             Y=Y^(1/2);
             app.GRMSYEditField.Value=Y;
            [pxx_zaxis,f_zaxis] = pwelch( glevelz,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes6,f_zaxis,pxx_zaxis)
            limz=(f_zaxis>0)&(f_zaxis<=2000);
             Z=trapz(f_zaxis(limz),pxx_zaxis(limz));
             Z=Z^(1/2);
             app.GRMSZEditField.Value=Z;
                         else
                                   m=max(A.(cell2mat(A.Properties.VariableNames(2))));
      tolerance=1e-15;

 ma=ismembertol(A.(cell2mat(A.Properties.VariableNames(2))),m,tolerance);
xm=find(ma==1);
maxdata=A.(cell2mat(A.Properties.VariableNames(2)))((xm):(end));
      i=1;
while(i<(n(1)+1))
   if(maxdata(i)<=app.EnterlowervEditField.Value)
        upper_bound=maxdata(i);
        lower_bound=maxdata(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.EnterlowervEditField.Value-upper_bound)>abs(app.EnterlowervEditField.Value-lower_bound))
app.EnterlowervEditField.Value=lower_bound;
else
    app.EnterlowervEditField.Value=upper_bound;

end
% for upperv
 j=1;


while(j<(n(1)+1))
   if(maxdata(j)<=app.EnteruppervEditField.Value)
        upper_bound2=maxdata(j);
        lower_bound2=maxdata(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.EnteruppervEditField.Value-upper_bound2)>abs(app.EnteruppervEditField.Value-lower_bound2))
app.EnteruppervEditField.Value=lower_bound2;
else
    app.EnteruppervEditField.Value=upper_bound2;

end
                  T=readtable(app.filenameEditField.Value);
     
idxlv=ismembertol(maxdata,app.EnterlowervEditField.Value,tolerance);
idxlvf=find(idxlv==1);
idxuv=ismembertol(maxdata,app.EnteruppervEditField.Value,tolerance);
idxuvf=find(idxuv==1);
idxlvf=idxlvf+xm-1;
idxuvf=idxuvf+xm-1;
idxlt=A.(cell2mat(A.Properties.VariableNames(1)))(idxlvf);
idxut=A.(cell2mat(A.Properties.VariableNames(1)))(idxuvf);
tolerance1=1e-6;
 tl=ismembertol(T.(cell2mat(T.Properties.VariableNames(1))),idxlt,tolerance1);
 xlf=find(tl==1);
  tu=ismembertol(T.(cell2mat(T.Properties.VariableNames(1))),idxut,tolerance1);
 xuf=find(tu==1);
 timedata2=T.(cell2mat(T.Properties.VariableNames(1)))((xlf):(xuf));
            voltagedatavx=T.(cell2mat(T.Properties.VariableNames(2)))((xlf):(xuf));
               voltagedatavy=T.(cell2mat(T.Properties.VariableNames(3)))((xlf):(xuf));
               voltagedatavz=T.(cell2mat(T.Properties.VariableNames(4)))((xlf):(xuf));
               glevelx=voltagedatavx./app.SensitivityofxEditField.Value;
                 glevely=voltagedatavy./app.SensitivityofyEditField.Value;
               glevelz=voltagedatavz./app.SensitivityofzEditField.Value;
                   [pxx_xaxis,f_xaxis] = pwelch( glevelx,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes4,f_xaxis,pxx_xaxis)
            limx=(f_xaxis>0)&(f_xaxis<=2000);
            X=trapz(f_xaxis(limx),pxx_xaxis(limx));
            X=(X)^(1/2);
            app.GRMSXEditField.Value=X;
            [pxx_yaxis,f_yaxis] = pwelch( glevely,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes5,f_yaxis,pxx_yaxis)
            limy=(f_yaxis>0)&(f_yaxis<=2000);
            Y=trapz(f_yaxis(limy),pxx_yaxis(limy));
            Y=(Y)^(1/2);
            app.GRMSYEditField.Value=Y;
            [pxx_zaxis,f_zaxis] = pwelch( glevelz,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes6,f_zaxis,pxx_zaxis)
            limz=(f_zaxis>0)&(f_zaxis<=2000);
            Z=trapz(f_zaxis(limz),pxx_zaxis(limz));
            Z=(Z)^(1/2);
            app.GRMSZEditField.Value=Z;

                         end
        
        end

        % Button pushed function: PlotGlevelsButton
        function PlotGlevelsButtonPushed(app, event)
            A=readtable(app.filenameEditField_2.Value);
 B=readtable(app.filenameEditField.Value);
 n=size(A.(cell2mat(A.Properties.VariableNames(1))));
i=1;
while(i<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(2)))(i)>=app.EnterlowervEditField.Value)
        upper_bound=A.(cell2mat(A.Properties.VariableNames(2)))(i);
        lower_bound=A.(cell2mat(A.Properties.VariableNames(2)))(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.EnterlowervEditField.Value-upper_bound)>abs(app.EnterlowervEditField.Value-lower_bound))
app.EnterlowervEditField.Value=lower_bound;
else
    app.EnterlowervEditField.Value=upper_bound;

end

 tolerance=1e-6;
 v=ismembertol(A.(cell2mat(A.Properties.VariableNames(2))),app.EnterlowervEditField.Value,tolerance);
x1=find(v==1);
 y1=A.(cell2mat(A.Properties.VariableNames(1)))(x1);
  tolerance=1e-6;
 v2=ismembertol(B.(cell2mat(B.Properties.VariableNames(1))),y1,tolerance);
 x13=find(v2==1);
  m=max(A.(cell2mat(A.Properties.VariableNames(2))));
      tolerance=1e-6;

 ma=ismembertol(A.(cell2mat(A.Properties.VariableNames(2))),m,tolerance);
xm=find(ma==1);
maxdata=A.(cell2mat(A.Properties.VariableNames(2)))((xm):(end));
 j=1;


while(j<(n(1)+1))
   if(maxdata(j)<=app.EnteruppervEditField.Value)
        upper_bound2=maxdata(j);
        lower_bound2=maxdata(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.EnteruppervEditField.Value-upper_bound2)>abs(app.EnteruppervEditField.Value-lower_bound2))
app.EnteruppervEditField.Value=lower_bound2;
else
    app.EnteruppervEditField.Value=upper_bound2;

end
idxuv=ismembertol(maxdata,app.EnteruppervEditField.Value,tolerance);
idxuvf=find(idxuv==1);
idxuvf=idxuvf+xm-1;
idxt=A.(cell2mat(A.Properties.VariableNames(1)))(idxuvf);
tolerance1=1e-6;
 idxtf=ismembertol(B.(cell2mat(B.Properties.VariableNames(1))),idxt,tolerance1);
 indexut=find(idxtf==1);
 timedatav=B.(cell2mat(B.Properties.VariableNames(1)))((x13):(indexut));
               voltagedatavx=B.(cell2mat(B.Properties.VariableNames(2)))((x13):(indexut));
               voltagedatavy=B.(cell2mat(B.Properties.VariableNames(3)))((x13):(indexut));
               voltagedatavz=B.(cell2mat(B.Properties.VariableNames(4)))((x13):(indexut));
                 glevelx=voltagedatavx./app.SensitivityofxEditField.Value;
                 glevely=voltagedatavy./app.SensitivityofyEditField.Value;
                glevelz=voltagedatavz./app.SensitivityofzEditField.Value;
                velocitydata=A.(cell2mat(A.Properties.VariableNames(2)))((x1):(idxuvf));
                timedata=A.(cell2mat(A.Properties.VariableNames(1)))((x1):(idxuvf));
                yyaxis (app.UIAxes,'left')
                plot(app.UIAxes,timedatav,glevelx,"Color",[0 0.447 0.7410]);
                yyaxis (app.UIAxes,'right')
               plot(app.UIAxes,timedata,velocitydata)
               yyaxis (app.UIAxes2,'left')
                plot(app.UIAxes2,timedatav,glevely,"Color",[0 0.447 0.7410]);
                yyaxis (app.UIAxes2,'right')
              plot(app.UIAxes2,timedata,velocitydata)
             yyaxis (app.UIAxes3,'left')
                plot(app.UIAxes3,timedatav,glevelz,"Color",[0 0.447 0.7410]);
                yyaxis (app.UIAxes3,'right')
              plot(app.UIAxes3,timedata,velocitydata)
           
        end

        % Button pushed function: PlotPSDButton
        function PlotPSDButtonPushed(app, event)
              
                 A=readtable(app.filenameEditField_2.Value);
 B=readtable(app.filenameEditField.Value);
 n=size(A.(cell2mat(A.Properties.VariableNames(1))));
i=1;
while(i<(n(1)+1))
   if(A.(cell2mat(A.Properties.VariableNames(2)))(i)>=app.EnterlowervEditField.Value)
        upper_bound=A.(cell2mat(A.Properties.VariableNames(2)))(i);
        lower_bound=A.(cell2mat(A.Properties.VariableNames(2)))(i-1);
        break;
   end
   i=i+1;
end

if(abs(app.EnterlowervEditField.Value-upper_bound)>abs(app.EnterlowervEditField.Value-lower_bound))
app.EnterlowervEditField.Value=lower_bound;
else
    app.EnterlowervEditField.Value=upper_bound;

end

 tolerance=1e-6;
 v=ismembertol(A.(cell2mat(A.Properties.VariableNames(2))),app.EnterlowervEditField.Value,tolerance);
idxlv=find(v==1);
 idxlvf=A.(cell2mat(A.Properties.VariableNames(1)))(idxlv);
  tolerance=1e-6;
 idxlt=ismembertol(B.(cell2mat(B.Properties.VariableNames(1))),idxlvf,tolerance);
 idxltf=find(idxlt==1);
  m=max(A.(cell2mat(A.Properties.VariableNames(2))));
      tolerance=1e-6;

 ma=ismembertol(A.(cell2mat(A.Properties.VariableNames(2))),m,tolerance);
xm=find(ma==1);
maxdata=A.(cell2mat(A.Properties.VariableNames(2)))((xm):(end));
 j=1;


while(j<(n(1)+1))
   if(maxdata(j)<=app.EnteruppervEditField.Value)
        upper_bound2=maxdata(j);
        lower_bound2=maxdata(j-1);
        break;
   end
   j=j+1;
end

if(abs(app.EnteruppervEditField.Value-upper_bound2)>abs(app.EnteruppervEditField.Value-lower_bound2))
app.EnteruppervEditField.Value=lower_bound2;
else
    app.EnteruppervEditField.Value=upper_bound2;

end
idxuv=ismembertol(maxdata,app.EnteruppervEditField.Value,tolerance);
idxuvf=find(idxuv==1);
idxuvf=idxuvf+xm-1;
idxtt=A.(cell2mat(A.Properties.VariableNames(1)))(idxuvf);
tolerance1=1e-6;
 indexut=ismembertol(B.(cell2mat(B.Properties.VariableNames(1))),idxtt,tolerance1);
 xlf=find(indexut==1);
 timedatav=B.(cell2mat(B.Properties.VariableNames(1)))((idxltf):(xlf));
               voltagedatavx=B.(cell2mat(B.Properties.VariableNames(2)))((idxltf):(xlf));
               voltagedatavy=B.(cell2mat(B.Properties.VariableNames(3)))((idxltf):(xlf));
               voltagedatavz=B.(cell2mat(B.Properties.VariableNames(4)))((idxltf):(xlf));
                 glevelx=voltagedatavx./app.SensitivityofxEditField.Value;
                 glevely=voltagedatavy./app.SensitivityofyEditField.Value;
                glevelz=voltagedatavz./app.SensitivityofzEditField.Value;
                [pxx_xaxis,f_xaxis] = pwelch( glevelx,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes4,f_xaxis,pxx_xaxis)
            limx=(f_xaxis>0)&(f_xaxis<=2000);
            X=trapz(f_xaxis(limx),pxx_xaxis(limx));
            X=(X)^(1/2);
            app.GRMSXEditField.Value=X;
            [pxx_yaxis,f_yaxis] = pwelch( glevely,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes5,f_yaxis,pxx_yaxis)
            limy=(f_yaxis>0)&(f_yaxis<=2000);
            Y=trapz(f_yaxis(limy),pxx_yaxis(limy));
            Y=(Y)^(1/2);
            app.GRMSYEditField.Value=Y;
            [pxx_zaxis,f_zaxis] = pwelch( glevelz,2048,0,2048,app.EntervalueofSamplingrateEditField.Value);
            loglog(app.UIAxes6,f_zaxis,pxx_zaxis) 
            limz=(f_zaxis>0)&(f_zaxis<=2000);
            Z=trapz(f_zaxis(limz),pxx_zaxis(limz));
            Z=(Z)^(1/2);
            app.GRMSZEditField.Value=Z;

        end

        % Button pushed function: ExportGraphicsButton
        function ExportGraphicsButtonPushed(app, event)
            exportgraphics(app.UIAxes,"plot.pdf","Append",true);
            exportgraphics(app.UIAxes2,"plot.pdf","Append",true);
            exportgraphics(app.UIAxes3,"plot.pdf","Append",true);
            exportgraphics(app.UIAxes4,"plot.pdf","Append",true);
            exportgraphics(app.UIAxes5,"plot.pdf","Append",true);
            exportgraphics(app.UIAxes6,"plot.pdf","Append",true);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            cla(app.UIAxes5)
            cla(app.UIAxes6)
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            app.UIAxes.Title=app.GRMSXEditField.Value;
        end

        % Value changed function: EnterlowertEditField
        function EnterlowertEditFieldValueChanged(app, event)
            value = app.EnterlowertEditField.Value;
            
        end

        % Value changed function: EnteruppertEditField
        function EnteruppertEditFieldValueChanged(app, event)
            value = app.EnteruppertEditField.Value;
            
        end

        % Value changed function: EnterlowervEditField
        function EnterlowervEditFieldValueChanged(app, event)
            value = app.EnterlowervEditField.Value;
            
        end

        % Value changed function: EnteruppervEditField
        function EnteruppervEditFieldValueChanged(app, event)
            value = app.EnteruppervEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Glevelx')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Glevely')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Glevelz')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'PSDx')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'PSDy')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'PSDz')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.EntervalueofSamplingrateLabel = uilabel(app.UIFigure);
            app.EntervalueofSamplingrateLabel.Text = {'Enter value of '; 'Sampling rate'};
            app.EntervalueofSamplingrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntervalueofSamplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @EntervalueofSamplingrateEditFieldValueChanged, true);
            app.ImportPanel = uipanel(app.UIFigure);
            app.ImportPanel.Title = 'Import';
            app.ImportsignalfileButton = uibutton(app.ImportPanel, 'push');
            app.ImportsignalfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportsignalfileButtonPushed, true);
            app.ImportsignalfileButton.Text = 'Import signal file';
            app.filenameEditFieldLabel = uilabel(app.ImportPanel);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.ImportPanel, 'text');
            app.filenameEditField.ValueChangedFcn = createCallbackFcn(app, @filenameEditFieldValueChanged2, true);
            app.ImportvelocityfileButton = uibutton(app.ImportPanel, 'push');
            app.ImportvelocityfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportvelocityfileButtonPushed, true);
            app.ImportvelocityfileButton.Text = 'Import velocity file';
            app.filenameEditField_2Label = uilabel(app.ImportPanel);
            app.filenameEditField_2Label.Text = 'filename';
            app.filenameEditField_2 = uieditfield(app.ImportPanel, 'text');
            app.filenameEditField_2.ValueChangedFcn = createCallbackFcn(app, @filenameEditField_2ValueChanged, true);
            app.InputSensitivitiesconvertintoVgforODARPanel = uipanel(app.UIFigure);
            app.InputSensitivitiesconvertintoVgforODARPanel.Title = 'Input Sensitivities (convert into V/g for ODAR)';
            app.SensitivityofxEditFieldLabel = uilabel(app.InputSensitivitiesconvertintoVgforODARPanel);
            app.SensitivityofxEditFieldLabel.Text = 'Sensitivity of x';
            app.SensitivityofxEditField = uieditfield(app.InputSensitivitiesconvertintoVgforODARPanel, 'numeric');
            app.SensitivityofxEditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityofxEditFieldValueChanged, true);
            app.SensitivityofyEditFieldLabel = uilabel(app.InputSensitivitiesconvertintoVgforODARPanel);
            app.SensitivityofyEditFieldLabel.Text = 'Sensitivity of y';
            app.SensitivityofyEditField = uieditfield(app.InputSensitivitiesconvertintoVgforODARPanel, 'numeric');
            app.SensitivityofyEditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityofyEditFieldValueChanged, true);
            app.SensitivityofzEditFieldLabel = uilabel(app.InputSensitivitiesconvertintoVgforODARPanel);
            app.SensitivityofzEditFieldLabel.Text = 'Sensitivity of z';
            app.SensitivityofzEditField = uieditfield(app.InputSensitivitiesconvertintoVgforODARPanel, 'numeric');
            app.SensitivityofzEditField.ValueChangedFcn = createCallbackFcn(app, @SensitivityofzEditFieldValueChanged, true);
            app.UseifvelocitiesarefromaccelerationanddecelerationPanel = uipanel(app.UIFigure);
            app.UseifvelocitiesarefromaccelerationanddecelerationPanel.Title = 'Use if velocities are from acceleration and deceleration';
            app.PlotGlevelsButton = uibutton(app.UseifvelocitiesarefromaccelerationanddecelerationPanel, 'push');
            app.PlotGlevelsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGlevelsButtonPushed, true);
            app.PlotGlevelsButton.Text = 'Plot Glevels';
            app.PlotPSDButton = uibutton(app.UseifvelocitiesarefromaccelerationanddecelerationPanel, 'push');
            app.PlotPSDButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPSDButtonPushed, true);
            app.PlotPSDButton.Text = 'Plot PSD';
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.TimePanel = uipanel(app.InputParametersPanel);
            app.TimePanel.Title = 'Time';
            app.EnteruppertEditFieldLabel = uilabel(app.TimePanel);
            app.EnteruppertEditFieldLabel.Text = 'Enter uppert';
            app.EnteruppertEditField = uieditfield(app.TimePanel, 'numeric');
            app.EnteruppertEditField.ValueChangedFcn = createCallbackFcn(app, @EnteruppertEditFieldValueChanged, true);
            app.EnterlowertEditFieldLabel = uilabel(app.TimePanel);
            app.EnterlowertEditFieldLabel.Text = 'Enter lowert';
            app.EnterlowertEditField = uieditfield(app.TimePanel, 'numeric');
            app.EnterlowertEditField.ValueChangedFcn = createCallbackFcn(app, @EnterlowertEditFieldValueChanged, true);
            app.VelocityPanel = uipanel(app.InputParametersPanel);
            app.VelocityPanel.Title = 'Velocity';
            app.EnterlowervEditFieldLabel = uilabel(app.VelocityPanel);
            app.EnterlowervEditFieldLabel.Text = 'Enter lowerv';
            app.EnterlowervEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.EnterlowervEditField.ValueChangedFcn = createCallbackFcn(app, @EnterlowervEditFieldValueChanged, true);
            app.EnteruppervEditFieldLabel = uilabel(app.VelocityPanel);
            app.EnteruppervEditFieldLabel.Text = 'Enter upperv';
            app.EnteruppervEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.EnteruppervEditField.ValueChangedFcn = createCallbackFcn(app, @EnteruppervEditFieldValueChanged, true);
            app.ExportGraphicsButton = uibutton(app.UIFigure, 'push');
            app.ExportGraphicsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGraphicsButtonPushed, true);
            app.ExportGraphicsButton.Text = 'ExportGraphics';
            app.PlotfortimePanel = uipanel(app.UIFigure);
            app.PlotfortimePanel.Title = 'Plot for time';
            app.PlotGlevelstimeButton = uibutton(app.PlotfortimePanel, 'push');
            app.PlotGlevelstimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGlevelstimeButtonPushed, true);
            app.PlotGlevelstimeButton.Text = 'Plot Glevels time';
            app.PlotPSDtimeButton = uibutton(app.PlotfortimePanel, 'push');
            app.PlotPSDtimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPSDtimeButtonPushed, true);
            app.PlotPSDtimeButton.Text = 'Plot PSD time';
            app.PlotforVelocityPanel = uipanel(app.UIFigure);
            app.PlotforVelocityPanel.Title = 'Plot for Velocity';
            app.PlotGlevelsvelocityButton = uibutton(app.PlotforVelocityPanel, 'push');
            app.PlotGlevelsvelocityButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGlevelsvelocityButtonPushed, true);
            app.PlotGlevelsvelocityButton.Text = 'Plot Glevels velocity';
            app.PlotPSDvelocityButton = uibutton(app.PlotforVelocityPanel, 'push');
            app.PlotPSDvelocityButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPSDvelocityButtonPushed, true);
            app.PlotPSDvelocityButton.Text = 'Plot PSD velocity';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GRMSPanel = uipanel(app.UIFigure);
            app.GRMSPanel.Title = 'GRMS';
            app.GRMSXEditFieldLabel = uilabel(app.GRMSPanel);
            app.GRMSXEditFieldLabel.Text = 'GRMSX';
            app.GRMSXEditField = uieditfield(app.GRMSPanel, 'numeric');
            app.GRMSYEditFieldLabel = uilabel(app.GRMSPanel);
            app.GRMSYEditFieldLabel.Text = 'GRMSY';
            app.GRMSYEditField = uieditfield(app.GRMSPanel, 'numeric');
            app.GRMSZEditFieldLabel = uilabel(app.GRMSPanel);
            app.GRMSZEditFieldLabel.Text = 'GRMSZ';
            app.GRMSZEditField = uieditfield(app.GRMSPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end