classdef output < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Max_LoadEditField               matlab.ui.control.NumericEditField
        Max_LoadEditFieldLabel          matlab.ui.control.Label
        DistanceattimeofmaxloadingmEditField  matlab.ui.control.NumericEditField
        DistanceattimeofmaxloadingmEditFieldLabel  matlab.ui.control.Label
        VelocityattimeofmaxloadingmsecEditField  matlab.ui.control.NumericEditField
        VelocityattimeofmaxloadingmsecEditFieldLabel  matlab.ui.control.Label
        RuntimesecEditField             matlab.ui.control.NumericEditField
        RuntimesecEditFieldLabel        matlab.ui.control.Label
        MaxDecelerationmsecsqEditField  matlab.ui.control.NumericEditField
        MaxDecelerationmsecsqEditFieldLabel  matlab.ui.control.Label
        MaxAccelerationmsecsqEditField  matlab.ui.control.NumericEditField
        MaxAccelerationmsecsqEditFieldLabel  matlab.ui.control.Label
        AtdistancemEditField            matlab.ui.control.NumericEditField
        AtdistancemEditFieldLabel       matlab.ui.control.Label
        MaximumVelocitymsecEditField    matlab.ui.control.NumericEditField
        MaximumVelocitymsecEditFieldLabel  matlab.ui.control.Label
        OUTPUTLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp1; % Main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Caller, distance_at_maxvel, vel_max, acc_max, acc_min, runtime, vel_at_maxload, dist_at_maxload, max_load)
            app.CallingApp1= Caller;

            app.MaximumVelocitymsecEditField.Value=vel_max;
            app.AtdistancemEditField.Value=distance_at_maxvel;
            app.MaxAccelerationmsecsqEditField.Value=acc_max;
            app.MaxDecelerationmsecsqEditField.Value=acc_min;
            % app.LengthofropemEditField.Value=length_of_rope;
            % app.MaximumnetloadonclampsNEditField.Value=max_load;
            app.RuntimesecEditField.Value=runtime;
            app.VelocityattimeofmaxloadingmsecEditField.Value=vel_at_maxload;
            app.DistanceattimeofmaxloadingmEditField.Value=dist_at_maxload;
            app.Max_LoadEditField.Value=max_load;
            % % rope_data 
            % app.Tension_1NEditField.Value=Tension_1;
            % app.Tension_0NEditField.Value=Tension_0;
            % app.DiametermmEditField.Value=diameter;
            % app.BrakingStrengthKNEditField.Value=strength;
            

            % % reaction
            % app.stClampNEditField.Value=first;
            % app.ndClampNEditField.Value=second;
            % app.rdClampNEditField.Value=third;
            % app.thClampNEditField.Value=fourth;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OUTPUTLabel = uilabel(app.UIFigure);
            app.OUTPUTLabel.Text = {'OUTPUT'; ''};
            app.Panel = uipanel(app.UIFigure);
            app.MaximumVelocitymsecEditFieldLabel = uilabel(app.Panel);
            app.MaximumVelocitymsecEditFieldLabel.Text = 'Maximum Velocity(m/sec)';
            app.MaximumVelocitymsecEditField = uieditfield(app.Panel, 'numeric');
            app.MaximumVelocitymsecEditField.Editable = 'off';
            app.AtdistancemEditFieldLabel = uilabel(app.Panel);
            app.AtdistancemEditFieldLabel.Text = 'At distance(m)';
            app.AtdistancemEditField = uieditfield(app.Panel, 'numeric');
            app.AtdistancemEditField.Editable = 'off';
            app.MaxAccelerationmsecsqEditFieldLabel = uilabel(app.Panel);
            app.MaxAccelerationmsecsqEditFieldLabel.Text = 'Max Acceleration(m/sec sq)';
            app.MaxAccelerationmsecsqEditField = uieditfield(app.Panel, 'numeric');
            app.MaxAccelerationmsecsqEditField.Editable = 'off';
            app.MaxDecelerationmsecsqEditFieldLabel = uilabel(app.Panel);
            app.MaxDecelerationmsecsqEditFieldLabel.Text = 'Max Deceleration(m/sec sq)';
            app.MaxDecelerationmsecsqEditField = uieditfield(app.Panel, 'numeric');
            app.MaxDecelerationmsecsqEditField.Editable = 'off';
            app.RuntimesecEditFieldLabel = uilabel(app.Panel);
            app.RuntimesecEditFieldLabel.Text = 'Run time(sec)';
            app.RuntimesecEditField = uieditfield(app.Panel, 'numeric');
            app.RuntimesecEditField.Editable = 'off';
            app.VelocityattimeofmaxloadingmsecEditFieldLabel = uilabel(app.Panel);
            app.VelocityattimeofmaxloadingmsecEditFieldLabel.Text = 'Velocity at time of max. loading(m/sec)';
            app.VelocityattimeofmaxloadingmsecEditField = uieditfield(app.Panel, 'numeric');
            app.VelocityattimeofmaxloadingmsecEditField.Editable = 'off';
            app.DistanceattimeofmaxloadingmEditFieldLabel = uilabel(app.Panel);
            app.DistanceattimeofmaxloadingmEditFieldLabel.Text = 'Distance at time of max. loading(m)';
            app.DistanceattimeofmaxloadingmEditField = uieditfield(app.Panel, 'numeric');
            app.DistanceattimeofmaxloadingmEditField.Editable = 'off';
            app.Max_LoadEditFieldLabel = uilabel(app.Panel);
            app.Max_LoadEditFieldLabel.Text = 'Max_Load';
            app.Max_LoadEditField = uieditfield(app.Panel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = output(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end